classdef EEGpalCS_mod_GEDAI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Rereference           matlab.ui.Figure
        EpochssizewavecyclesLabel_3    matlab.ui.control.Label
        EpochssizewavecyclesLabel_2    matlab.ui.control.Label
        ExtensionofoutputfilesLabel_3  matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        Denoising                      matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel_2  matlab.ui.control.Label
        LeadfieldMatrix                matlab.ui.control.DropDown
        EpochsSizeEditField            matlab.ui.control.NumericEditField
        EpochssizewavecyclesLabel      matlab.ui.control.Label
        Lowpass                        matlab.ui.control.NumericEditField
        RejectbadepochsLabel           matlab.ui.control.Label
        RejectbadepochsCheckbox        matlab.ui.control.CheckBox
        ENOVAthreshold                 matlab.ui.control.NumericEditField
        ParallelprocessingLabel        matlab.ui.control.Label
        ParallelprocessingCheckBox     matlab.ui.control.CheckBox
        ArtifactvisualizationLabel     matlab.ui.control.Label
        ArtifactvisualizationCheckBox  matlab.ui.control.CheckBox
        CancelButton                   matlab.ui.control.Button
        SaveinmemoryButton             matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        APPLYLabel                     matlab.ui.control.Label
        SuffixString                   matlab.ui.control.EditField
        SuffixstringLabel              matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        SelectUpperFolderLabel         matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        OUTPUTOPTIONSLabel             matlab.ui.control.Label
        GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object        
        DataTable        
        SettingTable
        batchHasCrashed 
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.SessionParameters.Rereference.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.Rereference.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.Rereference.SuffixString;
            
            
            % REREFERENCE
            
            % Reference selection
            app.ReferenceSelection.Items    = app.SessionParameters.Rereference.ReferenceItems;
            app.ReferenceSelection.Value    = app.SessionParameters.Rereference.ReferenceValue;
            
            % Electrodes cluster
            app.ClusterDropDown.Items       = app.SessionParameters.Rereference.ClusterItems;
            app.ClusterDropDown.Value       = app.SessionParameters.Rereference.ClusterValue;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            
            % OUTPUT OPTION
            SessionParameters.Rereference.FilesExtention    = app.FilesExtension.Value;
            SessionParameters.Rereference.SaveFolder        = app.SaveFolder.Value;
            SessionParameters.Rereference.SuffixString      = app.SuffixString.Value;
            
            
            % REREFERENCE
            
            % Reference selection
            SessionParameters.Rereference.ReferenceItems    = app.ReferenceSelection.Items;
            SessionParameters.Rereference.ReferenceValue    = app.ReferenceSelection.Value;
            
            % Electrodes cluster
            SessionParameters.Rereference.ClusterItems      = app.ClusterDropDown.Items;
            SessionParameters.Rereference.ClusterValue      = app.ClusterDropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values          
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            try
                app.SettingTable            = app.SessionParameters.Electrodes.SettingTable;
            catch
            end          
            
            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.FilesExtension.Value = '.set';
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
                try
                    ActiveElectrodes                = app.SettingTable.labels([app.SettingTable.include{:}]); % Selected electrodes only
                    app.ReferenceSelection.Items    = [{'Average'};ActiveElectrodes];
                    
                    ActiveClusters                  = app.SettingTable.cluster([app.SettingTable.include{:}]); % Clusters of selected electrodes only
                    app.ClusterDropDown.Items       = [{'all'};unique(ActiveClusters,'stable')];
                catch
                    app.ReferenceSelection.Items    = [{'Average'}; num2cell(1:64)'];
                    app.ClusterDropDown.Items       = 'all';
                end
                app.ReferenceSelection.Value        = 'Average';
                app.ClusterDropDown.Value           = 'all';
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end

            %Execute the batch System
            app.batchHasCrashed = false;
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                RunButtonPushed(app);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0 %normal mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output folder selected','warn');
                    return
                end
                
                if length(app.ReferenceSelection.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                    msgbox(sprintf('%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!'),...
                        'Average reference selected with another channel','warn');
                    return
                end
                
                if logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) &&...
                        strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                    AvgWarn = questdlg(sprintf('%s\n\n%s',...
                        'Average reference selected while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Average reference warning','OK','Cancel','Cancel');
                    if strcmpi(AvgWarn,'Cancel')
                        return
                    end
                end
                
                if strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                    ClustWarn = questdlg(sprintf('%s\n\n%s',...
                        'You have selected all clusters while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Clusters warning','OK','Cancel','Cancel');
                    if strcmpi(ClustWarn,'Cancel')
                        return
                    end
                end
                
                Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                    'Greetings Earthling!',...
                    'Me about to re-reference the data. Ready for the ride?',...
                    'Cluster on which re-referencing will be applied: ',app.ClusterDropDown.Value,...
                    'Number of files: ',height(app.DataTable),...
                    'A long list may take time to proceed (though not that much, let''s be honnest). In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
                
                if strcmpi(Run,'Cancel')
                    return
                end
            else %Batch mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the Re-reference module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end

                if length(app.ReferenceSelection.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))                    
                    msgbox(sprintf('%s\n\n%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!',...
                        'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                        'Average reference selected with another channel','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Rereference_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Re-reference Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Session parameters file:',app.EEGpalCS_Main.SessionParametersFilePath.Value);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');                
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','REFERENCE');
            if length(app.ReferenceSelection.Value) > 1
                fprintf(PifFileID,'%s\r\n','New reference:');
                fprintf(PifFileID,'\t\t%s\r\n',app.ReferenceSelection.Value{:});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','New reference:',app.ReferenceSelection.Value{:});
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            fprintf(PifFileID,'%s\t\t\t%s\r\n','Electrodes cluster:',app.ClusterDropDown.Value);
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
                Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                fprintf(PifFileID,'%s\r\n','Channels left in file:');
                fprintf(PifFileID,'\t\t%s\r\n',Included{:});
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN RE-REFERNCE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Re-reference...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Re-referencing.....');
            nbFile= height(app.DataTable);
            
            % Re-reference file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Re-referencing ',num2str(file),'/',num2str(nbFile)));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    % FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                                
                % Remove channels from non-scalp clusters
                if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
%                     NewRefData = data(:,strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value));
                    ScalpElectrodes = app.SettingTable.labels(strcmp(app.SettingTable.cluster,app.ClusterDropDown.Value)); % Selected electrodes only
                else
%                     NewRefData = data;
                    ScalpElectrodes = app.SettingTable.labels; % Selected scalp electrodes only
                end
                
                % Re-reference
                disp(['Re-referencing file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                if logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                    ref = 'avg';
                else
                    ref = find(contains(app.SettingTable.labels([app.SettingTable.include{:}]),app.ReferenceSelection.Value));
                end
                if ~logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) && ...
                        ~logical(sum(contains(app.ReferenceSelection.Value,ScalpElectrodes))) % The new reference is not within the scalp cluster
                    aux = 'aux'; % indicate that the reference is an auxiliary electrode (e.g., earlobes)
                else
                    aux = [];
                end
                NewRefData = ref_change(data,ref,aux);
                disp('Re-referencing completed')
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if strcmpi(aux,'aux')
                    AuxIndices = ~strcmpi(app.SettingTable.cluster,app.ClusterDropDown.Value);
                    for i = 1:length(AuxIndices)
                        if AuxIndices(i) == 1
                            NewRefData = [data(:,1), NewRefData];
                        elseif AuxIndices(i) > size(NewRefData,2)
                            NewRefData = [NewRefData, data(:,AuxIndices(i))];
                        else
                            NewRefData = [NewRefData(:,1:AuxIndices(i)-1), data(:,AuxIndices(i)), NewRefData(:,AuxIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewRefData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewRefData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewRefData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewRefData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewRefData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE DATA TABLE IN MAIN WINDOW
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewRefData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Re-reference processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            %Close
            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('Rereferencing done.');
            else %batch mode module successly completed                
                if app.batchHasCrashed
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end

            % Update parameters          
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Rereference
        function EEGpalCS_RereferenceCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_ReReferencing_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: RejectbadepochsCheckbox
        function RejectbadepochsCheckboxValueChanged(app, event)
            if app.RejectbadepochsCheckbox.Value
                app.ENOVAthreshold.Enable = "on";
            else
                app.ENOVAthreshold.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Rereference = uifigure('Visible', 'off');
            app.EEGpalCS_Rereference.Name = 'EEGpal_Re-reference';
            app.EEGpalCS_Rereference.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_RereferenceCloseRequest, true);
            app.GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel = uilabel(app.EEGpalCS_Rereference);
            app.GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel.Text = 'Generalized Eigenvalue De-Artifacting Instrument (GEDAI)';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_Rereference);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_Rereference);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_Rereference);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.EEGpalCS_Rereference);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.EEGpalCS_Rereference, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'GEDAIcorrected';
            app.APPLYLabel = uilabel(app.EEGpalCS_Rereference);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.ArtifactvisualizationCheckBox = uicheckbox(app.EEGpalCS_Rereference);
            app.ArtifactvisualizationCheckBox.Text = '';
            app.ArtifactvisualizationCheckBox.Value = true;
            app.ArtifactvisualizationLabel = uilabel(app.EEGpalCS_Rereference);
            app.ArtifactvisualizationLabel.Text = 'Artifact visualization:';
            app.ParallelprocessingCheckBox = uicheckbox(app.EEGpalCS_Rereference);
            app.ParallelprocessingCheckBox.Text = '';
            app.ParallelprocessingCheckBox.Value = true;
            app.ParallelprocessingLabel = uilabel(app.EEGpalCS_Rereference);
            app.ParallelprocessingLabel.Text = 'Parallel processing:';
            app.ENOVAthreshold = uieditfield(app.EEGpalCS_Rereference, 'numeric');
            app.ENOVAthreshold.Limits = [0 1];
            app.ENOVAthreshold.Enable = 'off';
            app.ENOVAthreshold.Value = 0.9;
            app.RejectbadepochsCheckbox = uicheckbox(app.EEGpalCS_Rereference);
            app.RejectbadepochsCheckbox.ValueChangedFcn = createCallbackFcn(app, @RejectbadepochsCheckboxValueChanged, true);
            app.RejectbadepochsCheckbox.Text = '';
            app.RejectbadepochsLabel = uilabel(app.EEGpalCS_Rereference);
            app.RejectbadepochsLabel.Text = 'Reject bad epochs';
            app.Lowpass = uieditfield(app.EEGpalCS_Rereference, 'numeric');
            app.Lowpass.Limits = [0 Inf];
            app.Lowpass.Value = 0.5;
            app.EpochssizewavecyclesLabel = uilabel(app.EEGpalCS_Rereference);
            app.EpochssizewavecyclesLabel.Text = 'Epochs size (wave cycles)';
            app.EpochsSizeEditField = uieditfield(app.EEGpalCS_Rereference, 'numeric');
            app.EpochsSizeEditField.Limits = [1 Inf];
            app.EpochsSizeEditField.RoundFractionalValues = 'on';
            app.EpochsSizeEditField.ValueDisplayFormat = '%.0f';
            app.EpochsSizeEditField.Value = 12;
            app.LeadfieldMatrix = uidropdown(app.EEGpalCS_Rereference);
            app.LeadfieldMatrix.Items = {'interpolated', 'precomputed'};
            app.LeadfieldMatrix.Value = 'interpolated';
            app.ExtensionofoutputfilesLabel_2 = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel_2.Text = 'Denoising strength';
            app.Denoising = uidropdown(app.EEGpalCS_Rereference);
            app.Denoising.Items = {'auto', 'auto+', 'auto-'};
            app.Denoising.Value = 'auto';
            app.HelpButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.ExtensionofoutputfilesLabel_3 = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel_3.Text = 'Leadfield matrix';
            app.EpochssizewavecyclesLabel_2 = uilabel(app.EEGpalCS_Rereference);
            app.EpochssizewavecyclesLabel_2.Text = 'Low-cut frequency (Hz)     ';
            app.EpochssizewavecyclesLabel_3 = uilabel(app.EEGpalCS_Rereference);
            app.EpochssizewavecyclesLabel_3.Enable = 'off';
            app.EpochssizewavecyclesLabel_3.Text = 'ENOVA Threshold (0-1)    ';
            % Show the figure after all components are created
            app.EEGpalCS_Rereference.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_GEDAI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Rereference)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Rereference)
        end
    end
end