classdef EEGpalCS_mod_ICA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_ICA                  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Step1WarningLabel             matlab.ui.control.Label
        ICAintroduction               matlab.ui.control.Label
        ICALabel                      matlab.ui.control.Label
        TitleStep1                    matlab.ui.control.Label
        StartICAdecompositionButton   matlab.ui.control.Button
        Step1DoneCheckBox             matlab.ui.control.CheckBox
        CenterPanel                   matlab.ui.container.Panel
        TitleStep2                    matlab.ui.control.Label
        FilteringInstruction_2        matlab.ui.control.Label
        InspectionTable               matlab.ui.control.Table
        RightPanel                    matlab.ui.container.Panel
        CleanRawDataInstructionLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel            matlab.ui.control.Label
        ExtensionofoutputfilesLabel   matlab.ui.control.Label
        FilesExtension                matlab.ui.control.DropDown
        SelectUpperFolderLabel        matlab.ui.control.Label
        SelectFolderButton            matlab.ui.control.Button
        SavingfolderLabel             matlab.ui.control.Label
        SaveFolder                    matlab.ui.control.TextArea
        SuffixstringLabel             matlab.ui.control.Label
        SuffixString                  matlab.ui.control.EditField
        APPLYLabel                    matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        TitleStep3                    matlab.ui.control.Label
        StartICArecompositionButton   matlab.ui.control.Button
        DeleteintermediatefilesLabel  matlab.ui.control.Label
        deleteIntermediateFile        matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

   
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        NewDataCell
        
%         CL_options
%         CRD_options
    end
    
    properties (Access = public)
        Study_Parameters
        SessionParameters
    end
    
    methods (Access = private)
               
        function out = str2vec(~,str)
            cln = find(str==':');
            if length(cln) == 1
                out = str2double(str(1:cln-1)) : str2double(str(cln+1:end));
            elseif length(cln) == 2
                out = str2double(str(1:cln(1)-1)) : str2double(str(cln(1)+1:cln(2)-1)) : str2double(str(cln(2)+1:end));
            else
                disp('did not understand the string, sorry!')
            end
        end
        
        function applyStudyParam(app)
            % Channels to process
            app.ChanCompTable.Data          = app.Study_Parameters.Filtering.ChanCompTable;
            
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.Study_Parameters.Filtering.FilesExtention;
            app.SaveFolder.Value            = app.Study_Parameters.Filtering.SaveFolder;
            app.SuffixString.Value          = app.Study_Parameters.Filtering.SuffixString;
            
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            % Channels to process
            Study_Parameters.Filtering.ChanCompTable        = app.ChanCompTable.Data;
            
            
            % OUTPUT OPTION
            Study_Parameters.Filtering.FilesExtention       = app.FilesExtension.Value;
            Study_Parameters.Filtering.SaveFolder           = app.SaveFolder.Value;
            Study_Parameters.Filtering.SuffixString         = app.SuffixString.Value;
            
        end
        
        function applySessionParam(app)
            % Channels to process
            app.ChanCompTable.Data          = app.SessionParameters.Filtering.ChanCompTable;
            
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.SessionParameters.Filtering.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.Filtering.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.Filtering.SuffixString;
          
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % Channels to process
            SessionParameters.Filtering.ChanCompTable   = app.ChanCompTable.Data;
            
            
            % OUTPUT OPTION
            SessionParameters.Filtering.FilesExtention  = app.FilesExtension.Value;
            SessionParameters.Filtering.SaveFolder      = app.SaveFolder.Value;
            SessionParameters.Filtering.SuffixString    = app.SuffixString.Value;
            
        end
        
        function componentsToRemove = inspectionComponents(app,fileName,filePath, indices)
            
            %initialisation EEGLAB variable
            ALLEEG=struct; CURRENTSET=0;
            
            %load dataset
            EEG = pop_loadset('filename',fileName,'filepath',filePath);
            [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
            
            if (~isempty(EEG.icawinv)) %check if ica decomposition has been done
                %Tag the EEG component
                f = msgbox("Wait a sort time (Labels are currentely process)","","help");
                EEG = pop_iclabel(EEG, 'default'); 
                [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                close(f)
                
                %display the 24 first ICA components
                pop_selectcomps(EEG, [1:24] );
                [ALLEEG EEG] = eeg_store(ALLEEG, EEG, CURRENTSET);
    
                h = inputdlg(['Enter manually here the number of the component to remove (do not use the button on the windows Reject components by map). ' ...
                    'Separate each number by a space (e.g. 2 5)'],'Bad components specification');
                
                if (isempty(h))
                    componentsToRemove=[];
                else
                    componentsToRemove = str2num(h{1});
                end
            else
                f = msgbox("It seems that no ICA decomposition has been performed with this file. Please run first the step1 on it","","warn");
                uiwait(f);
                componentsToRemove=[];
                app.InspectionTable.Data.Inspection(indices)=false;
            end
            close all
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            % Update UI with input values
            app.DataTable = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
             app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
             app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;           
            
             
            % Create folder for intermediate files
            mkdir (".\ICAtemp")
            
            %convert table of input file into a cell
            app.NewDataCell=table2cell(app.DataTable);
            
            %check if the files are in .set format
            temp=app.NewDataCell{1,1}; 
            temp=temp(end-3:end); %extraction of the extension
            if ~strcmp (temp,".set")
                %add the code here for convert to .set 
            end
            
            %creation of the Inspection table
            if ~isempty(app.DataTable)
                nRows = height(app.DataTable);
            else
                nRows = 1;
            end
            labels      = string({'FileNames','Component_to_remove','Inspection'});
            app.InspectionTable.Data = table('Size',[nRows length(labels)],...
                'VariableTypes',["string", "cell","logical"],'VariableNames',labels);
            
            %Fill this table
            for z=1:nRows
                app.InspectionTable.Data.FileNames(z)=app.NewDataCell{z,1}; %fill the file name
            end

           
        end

        % Callback function
        function ChanCompTableCellEdit(app, event)
            app.ChanCompIndices = table2array(app.ChanCompTable.Data([app.ChanCompTable.Data.process{:}]',1))';
            app.CL_options.lineNoiseChannels = num2str(app.ChanCompIndices);
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Callback function
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Callback function
        function SaveAsButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output flôlder selected','warn');
                return
            end


            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
%             % Last official update
%             fileID = fopen(strcat(currentfile.folder,'\',currentfile.name)); % open this file
%             update = char(string(textscan(fileID,'%s',1,'HeaderLines',1,'delimiter','\n'))); % Extract 'Update' line
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Filtering+_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpalCS Filtering+ Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\r\n','Processed channels:');
                fprintf(PifFileID,'\t\t%s\t\t%s\t\t%s\r\n',app.ChanCompTable.Data{:,:}');
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    if app.nChan > height(app.SessionParameters.Electrodes.SettingTable) % Extra channels not added to the electrode setting
                        fprintf(PifFileID,'\t\t%s\r\n',string(height(app.SessionParameters.Electrodes.SettingTable)+1:app.nChan));
                    else
                        fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                    end
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
%                 Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                 fprintf(PifFileID,'%s\r\n','Channels left in file:');
%                 fprintf(PifFileID,'\t\t%s\r\n',Included{:});
                if length(unique(app.DataTable.nChan)) > 1
                    fprintf(PifFileID,'\t\t%s\r\n','NOTE: number of channels inconsistent between files. The information above are based on the first file.');
                end
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file. Might reduce the accuracy of CLEAN RAW DATA (if used).');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RESAMPLING');
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','FILTERING');
            if strcmpi(app.SwitchFiltering.Value,'On')
                fprintf(PifFileID,'%s\t\t%s\r\n','Filtering method:',app.FilterType.Value);
                if strcmp(app.FilterType.Value,'High+Low-pass')
                    fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                    fprintf(PifFileID,'%s\t%d\r\n','High-pass filter order:',app.HPorder.Value);
                    fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                    fprintf(PifFileID,'%s\t%d\r\n','Low-pass filter order:',app.LPorder.Value);
                elseif strcmp(app.FilterType.Value,'High-pass')
                    fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                    fprintf(PifFileID,'%s\t%d\r\n','High-pass filter order:',app.HPorder.Value);
                elseif strcmp(app.FilterType.Value,'Low-pass')
                    fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                    fprintf(PifFileID,'%s\t%d\r\n','Low-pass filter order:',app.LPorder.Value);
                end
            else
                fprintf(PifFileID,'%s\r\n','No filtering');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','LINE NOISE');
            if ~strcmpi(app.LineNoise.Value,'No')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Line noise cleaning method:',app.LineNoise.Value);
                if contains(app.LineNoise.Value,'notch')
                    fprintf(PifFileID,'%s\t\t%d\r\n','Notch frequency:',app.FilterNotch.NotchFreq);
                    fprintf(PifFileID,'%s\t%g %g\r\n','Notch frequency range:',app.FilterNotch.NotchFreqRange);
                    fprintf(PifFileID,'%s\t\t%d\r\n','Notch filter order:',app.FilterNotch.NotchOrder);
                elseif contains(app.LineNoise.Value,'CleanLine')
                    fprintf(PifFileID,'%s\r\n','CleanLineNoise is an eeglab plugin developed for the PREP pipeline.');
                    fprintf(PifFileID,'%s\r\n','It is an update from the original CleanLine eeglab plugin.');
                    CLoptions = fieldnames(app.CL_options);
                    CLoptions(:,2) = {': '};
                    CLoptions(:,3) = cellfun(@num2str,struct2cell(app.CL_options),'UniformOutput',false);
                    CLoptions = CLoptions';
                    fprintf(PifFileID,'\r\n%s\r\n','CleanLineNoise options:');
                    fprintf(PifFileID,'\t%s%s%s\r\n',CLoptions{:});
                end
            else
                fprintf(PifFileID,'%s\r\n','No line noise cleaning');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CLEAN RAW DATA (eeglab plugin)');
            if strcmp(app.CRD_Switch.Value,'On')
                fprintf(PifFileID,'%s\r\n','Used the clean_artifacts() eeglab plugin.');
                fprintf(PifFileID,'%s\r\n','It is an update from the original clean_rawdata() eeglab plugin.');
                CRDoptions = fieldnames(app.CRD_options);
                CRDoptions(:,2) = {': '};
                CRDoptions(:,3) = cellfun(@num2str,struct2cell(app.CRD_options),'UniformOutput',false);
                CRDoptions = CRDoptions';
                
                fprintf(PifFileID,'\r\n%s\r\n','Channel drift removal:');
                if app.CRD_Drift.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,2});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Bad channels detection:');
                if app.CRD_BadChan.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,4:6});
                    fprintf(PifFileID,'%s\r\n','In the original script, bad channels are automatically removed for interpolation.');
                    fprintf(PifFileID,'%s\r\n','Here, bad channels are flagged, and it''s up to the user to decide wether to interpolate them or not.');
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Artifact Subspace Reconstruction (ASR):');
                if app.CRD_ASR.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,8:10});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Bad periods removal:');
                if app.CRD_BadPeriods.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,12:13});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
            else
                fprintf(PifFileID,'%s\r\n','CleanRawData not used');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(3))';num2cell(app.DataTable.(4))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN FILTERING+
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Filtering+...')
            StartTime = datetime('now');
            tStart = tic;
            
            for file = 1:height(app.DataTable)
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    if ~isempty(events)
                        events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    end
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    if ~isempty(events)
                        events = [events.latency(:)-1, events.latency(:)-1, events{:,2}]; % Convert to Cartool MRK format (onset, offset, code)
                    end
                    if ismember('duration', events.Properties.VariableNames)
                        events(:,2) = events.latency(:)-1 + events.duration(:);
                    end
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp(['File loaded (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end


                % Data to process
                NewData = data; % After re-sampling (which is fast) so to be able to add back the unprocessed channels
                
                % Remove unselected channels from ChanCompTable
%                 if isempty(app.ChanCompIndices)
%                     app.ChanCompIndices = 1:header.NumChan;
%                 end
%                 NewData = NewData(:,app.ChanCompIndices);
                if ~isempty(app.ChanCompIndices)
                    NewData = NewData(:,app.ChanCompIndices);
                end
                
                
                % Filtering
                if strcmpi(app.SwitchFiltering.Value,'On')
                    
                    % Update filter parameters (just to make sure...)
                    FilterPlots(app);
                    
                    % High-pass filter
                    if contains(app.FilterType.Value,'High')
                        disp(['Applying high-pass filter (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        NewData = filtfilt(app.FilterNotch.FilterKernelHP,1,NewData);
                    end
                    
                    % Low-Pass Filter
                    if contains(app.FilterType.Value,'Low')
                        disp(['Applying low-pass filter (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        NewData = filtfilt(app.FilterNotch.FilterKernelLP,1,NewData);
                    end
                    disp('Filtering completed')
                end
                
                
                % Line Noise
                if ~strcmpi(app.LineNoise.Value,'Off')
                    
                    % Notch filter
                    if contains(app.LineNoise.Value,'notch')
                        disp(['Applying ', app.LineNoise.Value])
                        NewData = filtfilt(app.FilterNotch.NotchKernel,1,NewData);
                    
                    % CleanLineNoise
                    elseif contains(app.LineNoise.Value,'CleanLine')
                        disp(['Applying CleanLineNoise (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        if isstring(app.CL_options.lineNoiseChannels)
                            app.CL_options.lineNoiseChannels = str2vec(app,app.CL_options.lineNoiseChannels);
                        end
                        signal.data = NewData'; % EEGlab organizes EEG data with rows representing the electrodes
                        signal.srate = app.SamplingRate;
                        signal = cleanLineNoise(signal,app.CL_options); % [signal, lineNoiseOut]
                        NewData = signal.data';
                    end
                    disp('Line noise cleaning completed')
                end
                
                
                % CleanRawData
                if strcmp(app.CRD_Switch.Value,'On')
                    disp(['Applying CleanRawData (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,[6 7 2]);
                        Channels.labels     = char(Channels.labels);
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.x;
                        Channels.Y          = ElectrodeSetting.y;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                        Channels            = Channels(app.ChanCompIndices,:); % Keep only channels to process
                    catch
                        Channels = '';
                    end
                    EEG = eeglab_datastruct(openfilename,NewData,app.SamplingRate,events,Channels);
                    % convert structure to cell
                    options = fieldnames(app.CRD_options);
                    options(:,2) = struct2cell(app.CRD_options);
                    options = options([2 4:6 8:10 12:13],:);
                    options = options';
                    options = options(:)';
                    
                    % Compute clean_artifact() on all channels
                    optionsAllChan = options;
                    optionsAllChan(4) = {'off'};
                    optionsAllChan(6) = {'off'};
                    optionsAllChan(8) = {'off'};
                    EEG = clean_artifacts(EEG,optionsAllChan{:});
                    NewData = EEG.data';
                    
                    % Flag bad channels
                    if app.CRD_options.BadChan
                        disp(['Applying CleanRawData bad channels detection (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        optionsBadChan = options;
                        optionsBadChan(2) = {'off'};    % Highpass off
                        optionsBadChan(10) = {'off'};   % BurstCriterion off
                        optionsBadChan(14) = {'off'};   % BurstRejection off
                        optionsBadChan(18) = {'off'};   % WindowCriterion off
                        [~,~,~,removed_channels] = clean_artifacts(EEG,optionsBadChan{:});
                        
                        try % Interpolation table already exists
                            app.SessionParameters.Interpolation.CRD{file,2:end} = removed_channels';
                            app.SessionParameters.Interpolation.CRD{file,1}     = 100 * (sum(removed_channels) / length(removed_channels));
                            PercentInterp = 100 * (sum(removed_channels) / length(removed_channels));
                            app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                            %app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),app.SessionParameters.Interpolation.CRD);
                        catch
                            %app.SessionParameters.Interpolation.CRD = app.DataTable(:,[1 2]);
                            try
                                ColumnNames = ['%', cellstr(strcat(ElectrodeSetting.labels, ' (',num2str(ElectrodeSetting.nr),')'))'];
                            catch
                                ColumnNames = ['%', cellstr(strcat('e',num2str((1:header.NumChan)')))'];
                            end
                            app.SessionParameters.Interpolation.CRD = table('Size',[height(app.DataTable) length(ColumnNames)],...
                                'VariableTypes',[{'string'}, cellstr(repmat('logical',length(ColumnNames)-1,1))'],'VariableNames',ColumnNames);
                            app.SessionParameters.Interpolation.CRD{file,2:end} = removed_channels';
                            PercentInterp = 100 * (sum(removed_channels) / length(removed_channels));
                            app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                            %app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),app.SessionParameters.Interpolation.CRD{file,:});
                            
                            % For the Interpolation module
                            app.SessionParameters.Interpolation.CleanRawDataSwitch = 'Yes';
                        end
                    end
                end
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if ~isempty(app.ChanCompIndices)
                    UnprocessedIndices = table2array(app.ChanCompTable.Data(~[app.ChanCompTable.Data.process{:}]',1))';
                    for i = 1:length(UnprocessedIndices)
                        if UnprocessedIndices(i) == 1
                            NewData = [data(:,1), NewData];
                        elseif UnprocessedIndices(i) > size(NewData,2)
                            NewData = [NewData, data(:,UnprocessedIndices(i))];
                        else
                            NewData = [NewData(:,1:UnprocessedIndices(i)-1), data(:,UnprocessedIndices(i)), NewData(:,UnprocessedIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                else
                    suffix = app.SuffixString.Value; % = '';
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewData,app.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewData,app.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,[6 7 2]);
                        Channels.labels     = char(Channels.labels);
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.x;
                        Channels.Y          = ElectrodeSetting.y;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewData,app.SamplingRate,events,header.firstindex,Channels)
                end

                if strcmpi(app.CRD_Switch.Value,'On') && app.CRD_options.BadChan
                    % Save the bad channels detected by CRD (in case processing is interrupted)
                    BadChanFileName = fullfile(app.SaveFolder.Value{:},[FileName,'_ASR_BadChannels.csv']);
                    writematrix(app.SessionParameters.Interpolation.CRD{file,:},BadChanFileName)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewData,2);
                ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
                        
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Filtering+ processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Filtering+ button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_ICA
        function EEGpalCS_ICACloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: StartICAdecompositionButton
        function RunICAdecomposition(app, event)
            sizeDataTable=size(app.NewDataCell);
            
            %initialisation
            mkdir (".\ICAtemp\decomposed"); %create the temp folder for intermediate files
            
            %user input
            [fileLoc,pathLoc] = uigetfile('biosemi64AB.locs','Select the biosemi64AB.locs located in the folder *\eeglab_plugins\coordinateFile\ from your EEGpal directory'); %coordinate file
            
            h = waitbar(0,'Processing.....');
            
            %main loop
            for i=1:sizeDataTable(1)
                waitbar(i/sizeDataTable(1),h, strcat('Processing files ',num2str(i),'/',num2str(sizeDataTable(1))));
                
                %initialisation EEGLAB variable
                ALLEEG=struct; CURRENTSET=0;
                
                %Load dataset with EEGlab
                EEG = pop_loadset('filename',app.NewDataCell{i,1},'filepath',app.NewDataCell{i,2}(2:end));
                [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
                
                %Set EEGlab coordinate file
                EEG = pop_editset(EEG, 'chanlocs', strcat(pathLoc,fileLoc));
                [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                
                %run ICA
                EEG = pop_runica(EEG, 'icatype', 'runica', 'extended',1,'interrupt','on');
                [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                
                %save dataset
                outputfileICA=strcat(app.NewDataCell{i,1}(1:end-4),'_ICAdecomposed.set');
                EEG = pop_saveset( EEG, 'filename',outputfileICA,'filepath','.\ICAtemp\decomposed');
                [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                app.NewDataCell{i,1}=outputfileICA;
                app.NewDataCell{i,2}='..\ICAtemp\decomposed' %path location of the new temp file
                
                app.InspectionTable.Data.FileNames(i)=outputfileICA; %fill inspection table with the new file name 
                app.Step1DoneCheckBox.Value=true;
            end
            delete (h);  
            
        end

        % Cell edit callback: InspectionTable
        function InspectionTableCellEdit(app, event)
            indices = event.Indices;
            %newData = event.NewData;
            if(app.InspectionTable.Data.Inspection(indices(1)))             
                %function which call the component visualisation
                UnwantedComponents=inspectionComponents(app, app.NewDataCell{indices(1),1}, app.NewDataCell{indices(1),2}(2:end),indices); %argument2=filename, argument3=filepath
                
                app.InspectionTable.Data.Component_to_remove{indices(1)}=UnwantedComponents;
            end
            
            %pour accéder au valeurs, il faut utiliser app.InspectionTable.Data{1,2}{1}
            disp('after inspectionComponents function excution')
        end

        % Button pushed function: StartICArecompositionButton
        function RunICArecomposition(app, event)
            sizeDataCell=size(app.NewDataCell);
            
            %initialisation
            mkdir (".\ICAtemp\recomposed"); %create the temp folder for intermediate files
            
            h = waitbar(0,'Recomposition of signal.....');
            
            %main loop
            for i=1:sizeDataCell(1)
                waitbar(i/sizeDataCell(1),h, strcat('Recomposition of signal ',num2str(i),'/',num2str(sizeDataCell(1))));
                
                %initialisation EEGLAB variable
                ALLEEG=struct; CURRENTSET=0;
                
                if (app.InspectionTable.Data{i,3}) %check if the file has been inspected
                    %Load dataset with EEGlab
                    EEG = pop_loadset('filename',app.NewDataCell{i,1},'filepath',app.NewDataCell{i,2}(2:end));
                    [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
                    
                    %remove unwanted component and recompose the EEG signal
                    EEG = pop_subcomp( EEG,app.InspectionTable.Data{i,2}{1}, 0);
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    
                    %save dataset
                    outputfileEEG=strcat(app.NewDataCell{i,1}(1:end-18),'_ICApruned.set');
                    EEG = pop_saveset( EEG, 'filename',outputfileEEG,'filepath','.\ICAtemp\recomposed');
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    app.NewDataCell{i,1}=outputfileEEG;
                    app.NewDataCell{i,2}='..\ICAtemp\recomposed' %path location of the new temp file
                    
                end
                
            end
            delete (h);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpalCS_ICA.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {760, 760, 760};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {760, 760};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x', 248};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_ICA = uifigure('Visible', 'off');
            app.EEGpalCS_ICA.Name = 'EEGpalCS Data Cleaning';
            app.EEGpalCS_ICA.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_ICACloseRequest, true);
            app.EEGpalCS_ICA.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpalCS_ICA);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Step1WarningLabel = uilabel(app.LeftPanel);
            app.Step1WarningLabel.Text = 'This button will start the ICA decomposition of all the files listed in the main windows. This step could take serval hours depending on the number of files. ';
            app.ICAintroduction = uilabel(app.LeftPanel);
            app.ICAintroduction.Text = {'The Independent Compound Analysis (ICA) permit to decompose the EEG signal in serval compounds (step1). '; 'Then, you can select the unwanted compounds (mainly eyes movements related compound) (step2).'; 'Finally, you can rebuild the EEG signal without unwanted compounds and without losing any data point (step3). '; ''; 'IMPORTANT NOTE: You will have to perform these three steps in the row without closing this window for a clean result. Otherwise each steps will record intermediate files in the folder ./temp_ICA in a .set format. '};
            app.ICALabel = uilabel(app.LeftPanel);
            app.ICALabel.Text = 'ICA';
            app.TitleStep1 = uilabel(app.LeftPanel);
            app.TitleStep1.Text = 'Step1: Decomposition';
            app.StartICAdecompositionButton = uibutton(app.LeftPanel, 'push');
            app.StartICAdecompositionButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAdecomposition, true);
            app.StartICAdecompositionButton.Text = 'Start ICA decomposition';
            app.Step1DoneCheckBox = uicheckbox(app.LeftPanel);
            app.Step1DoneCheckBox.Enable = 'off';
            app.Step1DoneCheckBox.Text = {'Step1 '; 'Done'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.TitleStep2 = uilabel(app.CenterPanel);
            app.TitleStep2.Text = 'Step2: Components inspection';
            app.FilteringInstruction_2 = uilabel(app.CenterPanel);
            app.FilteringInstruction_2.Text = {'Click on the checkbox to inspect the ICA components for each file individually. '; 'The toolbox will show you the topography of the 24 first components with the labels estimated by ICAlabels (included in EEGlab). '; 'Please enter manually the indices of the component to be removed. Don’t use the classical buttons on the ‘Reject components by map’ windows (it does not work now). '};
            app.InspectionTable = uitable(app.CenterPanel);
            app.InspectionTable.ColumnName = {'FileNames'; 'Component_to_remove'; 'Inspection'};
            app.InspectionTable.RowName = {};
            app.InspectionTable.ColumnEditable = [false false true];
            app.InspectionTable.CellEditCallback = createCallbackFcn(app, @InspectionTableCellEdit, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.CleanRawDataInstructionLabel = uilabel(app.RightPanel);
            app.CleanRawDataInstructionLabel.Text = {'Reconstruct your EEG signal without the components tagged during step2. '; 'Only the files tick as inspected will be considered. '; ''};
            app.OUTPUTOPTIONSLabel = uilabel(app.RightPanel);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.RightPanel);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.RightPanel);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.RightPanel);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.RightPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.RightPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.RightPanel);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.RightPanel);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.RightPanel, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'ICApruned';
            app.APPLYLabel = uilabel(app.RightPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.RightPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.TitleStep3 = uilabel(app.RightPanel);
            app.TitleStep3.Text = 'Step3: Components rejection';
            app.StartICArecompositionButton = uibutton(app.RightPanel, 'push');
            app.StartICArecompositionButton.ButtonPushedFcn = createCallbackFcn(app, @RunICArecomposition, true);
            app.StartICArecompositionButton.Text = 'Start ICA recomposition';
            app.DeleteintermediatefilesLabel = uilabel(app.RightPanel);
            app.DeleteintermediatefilesLabel.Text = 'Delete intermediate files';
            app.deleteIntermediateFile = uidropdown(app.RightPanel);
            app.deleteIntermediateFile.Items = {'no', 'yes'};
            app.deleteIntermediateFile.Value = 'no';
            % Show the figure after all components are created
            app.EEGpalCS_ICA.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_ICA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_ICA)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_ICA)
        end
    end
end