classdef EEGpalCS_mod_Interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Interpolation         matlab.ui.Figure
        orLabel                        matlab.ui.control.Label
        BridgesSwitch                  matlab.ui.control.Switch
        HelpButton                     matlab.ui.control.Button
        LoadBridgeDetectionfileButton  matlab.ui.control.Button
        InterpolationAlgo              matlab.ui.control.Switch
        CartoolMatlabLabel             matlab.ui.control.Label
        InterpolationAlgoTitle         matlab.ui.control.Label
        BridgeDetection                matlab.ui.control.Label
        UploadTableButton              matlab.ui.control.Button
        SaveTableButton                matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        ShowTable                      matlab.ui.control.Table
        CancelButton                   matlab.ui.control.Button
        SaveinmemoryButton             matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        APPLYLabel                     matlab.ui.control.Label
        SuffixString                   matlab.ui.control.EditField
        SuffixstringLabel              matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        SelectUpperFolderLabel         matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        OUTPUTOPTIONSLabel             matlab.ui.control.Label
        sdOutliersInstruction          matlab.ui.control.Label
        sdOutliers_LOD                 matlab.ui.control.NumericEditField
        LevelofdecisionLabel           matlab.ui.control.Label
        sdOutliersSwitch               matlab.ui.control.Switch
        sdOutlierchannelsLabel         matlab.ui.control.Label
        CleanRawDataSwitch             matlab.ui.control.Switch
        CleanRawDataSwitchLabel        matlab.ui.control.Label
        BadChannelsDetection           matlab.ui.control.Label
        NumberMissing                  matlab.ui.control.NumericEditField
        NumberofmissingchannelsLabel   matlab.ui.control.Label
        InterpolateMissing             matlab.ui.control.CheckBox
        InterpolatemissingchannelsLabel  matlab.ui.control.Label
        ClusterDropDown                matlab.ui.control.DropDown
        ScalpelectrodesclusterDropDownLabel  matlab.ui.control.Label
        InterpolationInstruction       matlab.ui.control.Label
        INTERPOLATIONLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        EEGpalCS_Epoch  % Epoching object
        
        DataTable
        rootfolder
        InterpolationTable
        Manual_BadChannels
        CRD_BadChannels
        Out_BadChannels
        eBridge_BadChannels
        Out_Recompute
        SettingTable
        ParameterFilePath
        batchHasCrashed % Description
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            
            % OUTPUT OPTION
            
            app.FilesExtension.Value        = app.SessionParameters.Interpolation.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.Interpolation.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.Interpolation.SuffixString;
            
            
            % INTERPOLATION
            
            % Interpolation table          
            filesIN=app.EEGpalCS_Main.DataFilesTable.Data.File; 
            filesSAVED=app.SessionParameters.Interpolation.ShowTable.File;
            isEqual = all(cellfun(@(x,y) isequal(x,y), filesIN, filesSAVED)); % check if the input files are the same as the file saved otherwise leave the default Interpolation matrix

            if isEqual
                app.InterpolationTable          = app.SessionParameters.Interpolation.InterpolationTable; 
                app.ShowTable.Data              = app.SessionParameters.Interpolation.ShowTable;
            end


            % Electrodes cluster
            app.ClusterDropDown.Items       = app.SessionParameters.Interpolation.ClusterItems;
            app.ClusterDropDown.Value       = app.SessionParameters.Interpolation.ClusterValue;
        
            % Outliers
            app.Out_BadChannels             = app.SessionParameters.Interpolation.Outliers;
            app.sdOutliers_LOD.Value               = app.SessionParameters.Interpolation.Out_LOD;
            app.Out_Recompute               = app.SessionParameters.Interpolation.Out_Recompute;
            app.sdOutliersSwitch.Value        = app.SessionParameters.Interpolation.OutliersSwitch;
            
            % CleanRawData bad channels (might not have been computed...)
            try
                app.CRD_BadChannels             = app.SessionParameters.Interpolation.CRD;
                app.CleanRawDataSwitch.Value    = app.SessionParameters.Interpolation.CleanRawDataSwitch;
            catch
                app.CleanRawDataSwitch.Enable   = 'off';
            end
            
            % Manual bad channels selection (created only if a ShowTable cell has been edited)
            try
                app.Manual_BadChannels          = app.SessionParameters.Interpolation.Manual;
            catch
            end

            %interpolation algo
            app.InterpolationAlgo.Value   = app.SessionParameters.Interpolation.InterpolationAlgo;
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            
            % OUTPUT OPTION
            
            SessionParameters.Interpolation.FilesExtention      = app.FilesExtension.Value;
            SessionParameters.Interpolation.SaveFolder          = app.SaveFolder.Value;
            SessionParameters.Interpolation.SuffixString        = app.SuffixString.Value;
            
            
            % INTERPOLATION
            
            % Interpolation table
            SessionParameters.Interpolation.InterpolationTable  = app.InterpolationTable;
            SessionParameters.Interpolation.ShowTable           = app.ShowTable.Data;
            
            % Electrodes cluster
            SessionParameters.Interpolation.ClusterItems        = app.ClusterDropDown.Items;
            SessionParameters.Interpolation.ClusterValue        = app.ClusterDropDown.Value;
        
            % Outlier
            SessionParameters.Interpolation.Outliers            = app.Out_BadChannels;
            SessionParameters.Interpolation.Out_LOD             = app.sdOutliers_LOD.Value;
            SessionParameters.Interpolation.Out_Recompute       = app.Out_Recompute;
            SessionParameters.Interpolation.OutliersSwitch      = app.sdOutliersSwitch.Value;
        
            % CleanRawData bad channels (might not have been computed...)
            try
                SessionParameters.Interpolation.CRD             = app.CRD_BadChannels;
                SessionParameters.Interpolation.CleanRawDataSwitch  = app.CleanRawDataSwitch.Value;
            catch
            end
        
            % Manual bad channels selection (created only if a ShowTable cell has been edited)
            try
                SessionParameters.Interpolation.Manual              = app.Manual_BadChannels;
            catch
            end
            
            %interpolation algo
            SessionParameters.Interpolation.InterpolationAlgo   = app.InterpolationAlgo.Value;

        end
        
        
        function InterpolationTable = updateInterpTable(app,InterpolationTable)
            for column = 4:width(app.ShowTable.Data)
                InterpolationTable.(cell2mat(app.ShowTable.ColumnName(column))) = app.ShowTable.Data.(column);
            end
            PercentInterp = 100 * sum(InterpolationTable{:,2:end},2) / length(InterpolationTable{:,2:end});
            InterpolationTable{:,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
        end
        
        function ShowTable = updateShowTable(app,ShowTable)
            for column = 4:width(app.ShowTable.Data)
                ShowTable.Data.(column) = app.InterpolationTable.(cell2mat(ShowTable.ColumnName(column)));
            end
            PercentInterp = 100 * sum(ShowTable.Data{:,4:end},2) / length(ShowTable.Data{:,4:end});
            ShowTable.Data{:,3} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
        end        
               
        function updateInterpolationTable(app)
            % Update table
            if strcmp(app.sdOutliersSwitch.Value,'Yes') && strcmp(app.CleanRawDataSwitch.Value,'Yes') && strcmp(app.BridgesSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(app.Out_BadChanne{:,2:end} + app.CRD_BadChannels{:,2:end}+ logical(app.eBridge_BadChannels{:,2:end})+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.sdOutliersSwitch.Value,'Yes') && strcmp(app.CleanRawDataSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(app.Out_BadChannels{:,2:end} + app.CRD_BadChannels{:,2:end}+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.CleanRawDataSwitch.Value,'Yes') && strcmp(app.BridgesSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(app.CRD_BadChannels{:,2:end} + logical(app.eBridge_BadChannels{:,2:end})+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.sdOutliersSwitch.Value,'Yes') && strcmp(app.BridgesSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(app.Out_BadChannels{:,2:end} + logical(app.eBridge_BadChannels{:,2:end})+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.sdOutliersSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical (app.Out_BadChannels{:,2:end}+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.CleanRawDataSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(app.CRD_BadChannels{:,2:end}+app.Manual_BadChannels{:,4:end});
            elseif strcmp(app.BridgesSwitch.Value,'Yes')
                app.InterpolationTable{:,2:end} = logical(logical(app.eBridge_BadChannels{:,2:end})+app.Manual_BadChannels{:,4:end});
            else
                app.InterpolationTable     = app.Manual_BadChannels(:,3:end);
            end
            app.ShowTable                   = updateShowTable(app,app.ShowTable);
        end
    end
    
    methods (Access = public)
        
        function checkFlatReref(app)
            try
                    if ~strcmp(app.SessionParameters.Rereference.ReferenceValue_1{1},'Average') & numel(app.SessionParameters.Rereference.ReferenceValue_1) == 1
                        referenceChan=app.SessionParameters.Rereference.ReferenceValue_1{1};
                        %find the indice for the electrode
                        for z=1:size(app.SessionParameters.Electrodes.SettingTable,1)
                            if strcmp(app.SessionParameters.Electrodes.SettingTable.labels(z),referenceChan)
                                referenceChanIndice=z;
                            end
                        end

                        %remplissage avec la bad channel
                        for z=1:size(app.Manual_BadChannels,1)
                            app.Manual_BadChannels{z,referenceChanIndice+3}=true;
                        end
                        updateInterpolationTable(app);
                    end
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            %save of the currenpath (where are stored the input files)
            app.rootfolder=pwd;
            
            % Update UI with input values
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.SessionParametersFilePath.Value;            
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SettingTable                = app.SessionParameters.Electrodes.SettingTable;
            
            app.Out_Recompute               = 'yes';
            
            
            % Interpolation table
            if ~isempty(app.DataTable)
                nRows = height(app.DataTable);
            else
                nRows = 1;
            end
            labels      = app.SettingTable.labels(cell2mat(app.SettingTable.include));
            nr          = app.SettingTable.nr(cell2mat(app.SettingTable.include));
            ColumnNames = ['%', cellstr(strcat(labels, ' (',num2str(nr),')'))'];
            app.InterpolationTable = table('Size',[nRows length(ColumnNames)],...
                'VariableTypes',[{'string'}, cellstr(repmat('logical',length(ColumnNames)-1,1))'],'VariableNames',ColumnNames);
            
            % Show table
            app.ShowTable.ColumnName = ...
                [app.ShowTable.ColumnName(1:2); ColumnNames'];
            app.ShowTable.ColumnWidth = ...
                [app.ShowTable.ColumnWidth(1:3), repmat({50},1,length(ColumnNames)-1)];
            app.ShowTable.ColumnEditable = ...
                [app.ShowTable.ColumnEditable(1:3), true(1,length(ColumnNames)-1)];
            if ~isempty(app.DataTable)
                app.ShowTable.Data = [app.DataTable(:,[1 2]) app.InterpolationTable];
            end
            app.Manual_BadChannels = app.ShowTable.Data;            
            
           
            % Electrodes cluster (default values)
            ActiveClusters                  = app.SettingTable.cluster([app.SettingTable.include{:}]); % Clusters of selected electrodes only
            app.ClusterDropDown.Items       = ['all';unique(ActiveClusters,'stable')]';
            app.ClusterDropDown.Value       = app.ClusterDropDown.Items(1);
            
            
            %Fill with eBridge
            if isfield(app.SessionParameters,'ebridge') %check if ebridge has been estimate
                %check if same files
                if size(app.SessionParameters.ebridge.eBridgeTable,1) == nRows
                    sameFiles=true; 
                    for q=1:nRows
                        [~,filename1,~]=fileparts(app.ShowTable.Data.File{q});
                        [~,filename2,~]=fileparts(app.SessionParameters.ebridge.eBridgeTable.File(q));
                        if ~contains(filename1,filename2)
                            sameFiles=false;
                        end
                    end
                else
                    sameFiles=false;
                end

                %fill with detected bridges
                if sameFiles
                    app.BridgesSwitch.Enable = "on";                    
                    app.BridgesSwitch.Value = 'Yes';
                    app.eBridge_BadChannels = app.SessionParameters.ebridge.eBridgeTable(:,3:end);

                    %update table                    
                    updateInterpolationTable(app);

                end
            end

            %Fill with a flat channel due to a preceding Reref
            if isfield(app.SessionParameters,'Rereference')
                checkFlatReref(app);
            end
            
            % CleanRawData bad channels (if computed before the first the module is opened the first time)
            try
                if ~isempty(app.SessionParameters.Interpolation.CRD) %no bad channel data
                    app.CRD_BadChannels             = app.SessionParameters.Interpolation.CRD;
                    app.CleanRawDataSwitch.Value    = 'Yes';
                    app.CleanRawDataSwitch.Enable   = 'on';
                    app.CleanRawDataSwitchLabel.Enable = "on";
                    app.InterpolationTable          = app.CRD_BadChannels;
                    app.ShowTable                   = updateShowTable(app,app.ShowTable);
                end
            catch
                try
                    BadChannelsFiles=dir('*ASR_BadChannels.csv');
                    if size(BadChannelsFiles,1) == nRows
                        app.CRD_BadChannels=app.Manual_BadChannels(:,3:end);
                        for m=1:nRows
                            currentFileName = app.DataTable.File{m};
                            uploadFile = readtable(strcat(currentFileName(1:end-4),'_ASR_BadChannels.csv'));
                            for mm = 1:width(uploadFile)
                                if iscellstr(uploadFile.(mm)) || isstring(uploadFile.(mm))
                                    % Conversion en booléens
                                    uploadFile.(mm) = strcmpi(uploadFile.(mm), 'true');
                                end
                            end
                            app.CRD_BadChannels{m,:}=uploadFile{:,:};
                        end
                        app.CleanRawDataSwitch.Value    = 'Yes';
                        app.CleanRawDataSwitch.Enable   = 'on';
                        app.CleanRawDataSwitchLabel.Enable = "on";
                    else
                        app.CleanRawDataSwitch.Enable   = 'off';                    
                    end
                catch
                    app.CleanRawDataSwitch.Enable   = 'off';
                end
            end            
            
            % Update table            
            updateInterpolationTable(app);

            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.InterpolationAlgo.Value = 'Spherical';
                app.FilesExtension.Value = '.set';
            end

            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end

        %Execute the batch System
        app.batchHasCrashed = false;
        if app.SessionParameters.BatchProcessing.BatchExecution == 1
            if strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Automatic Bad Electrodes Rejection') %directely run the interpolation
                if ~isempty(app.SessionParameters.Interpolation.CRD)
                    app.CleanRawDataSwitch.Value    = 'Yes';
                    updateInterpolationTable(app);                    
                end
            
                %Fill with a flat channel due to a preceding Reref
                if isfield(app.SessionParameters,'Rereference') % rerun because in batch the parameters hs been removed
                    checkFlatReref(app);
                end
                
                RunButtonPushed(app);
            else
                rz = msgbox('Now it is time to select the electrodes for interpolation. Once you have done so, press Run. To continue the batch after that, press the "RUN BATCH" button again from the main window!');
            end
        end              


        end

        % Value changed function: ClusterDropDown
        function ClusterDropDownValueChanged(app, event)
            if strcmpi(app.ClusterDropDown.Value,'all')
                app.ShowTable.Data = [app.ShowTable.Data(:,[1 2]) app.InterpolationTable];
            else
                ClusterElectrodes = find(strcmpi(app.SettingTable.cluster,app.ClusterDropDown.Value));
                ClusterElectrodes = ClusterElectrodes + 1; % because of the first '%' columns the InterpolationTable
                app.ShowTable.Data = [app.ShowTable.Data(:,1:3) app.InterpolationTable(:,ClusterElectrodes)];
            end
        end

        % Value changed function: CleanRawDataSwitch
        function CleanRawDataSwitchValueChanged(app, event)
            if strcmp(app.CleanRawDataSwitch.Value,'Yes')
                % Check that the channels are the same for CRD and for the current setting
                if ~isequal(app.CRD_BadChannels.Properties.VariableNames,app.InterpolationTable.Properties.VariableNames) %||...
                        %~isequal(app.CRD_BadChannels.ColumnName,app.ShowTable.ColumnName(3:end))
                    msgbox(sprintf('%s\n\n%s\n%s\n\n%s','Channel labels inconsistent!', ...
                        'The channel labels of the CleanRawData output and the current electrode setting are inconsistent.',...
                        'Maybe you changed something in the electrode setting since you computed CleanRawData.',...
                        'Maybe you did something too complicated for me. I''m doing my best, you know...'),...
                        'Channel labels inconsistent','warn');
                    app.CleanRawDataSwitch.Enable   = 'off';
                    return
                end                  
            end

            % Update table
            updateInterpolationTable(app);
        end

        % Value changed function: sdOutliersSwitch
        function sdOutliersSwitchValueChanged(app, event)
            if strcmp(app.sdOutliersSwitch.Value,'Yes')
                if strcmp(app.Out_Recompute,'no')
                    app.InterpolationTable = app.Out_BadChannels;
                    app.ShowTable = updateShowTable(app,app.ShowTable);
                    app.InterpolationTable = updateInterpTable(app,app.InterpolationTable); % Recompute '%' column (yes, I know...)
                else
                    disp('Compute Outliers...')
                    StartTime = datetime('now');
                    tStart = tic;
                    
                    app.Out_BadChannels = app.InterpolationTable;
                    
                    for file = 1:height(app.DataTable)
                        % Load file
                        disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                        openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                        [~,~,FileExtension] = fileparts(openfilename);
                        if strcmpi(FileExtension,'.bdf')
                            [~,data] = open_bdf(openfilename);
                        elseif strcmpi(FileExtension,'.set')
                            [~,data] = open_eeglab(openfilename);
                        elseif strcmpi(FileExtension,'.sef')
                            [~,data] = open_sef(openfilename);
                        elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                            [~,data] = open_eph(openfilename);
                        else
                            if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                                disp('These tools are not intended for .freq or .ris files.')
                            else
                                disp('File extension not recognized.')
                            end
                            disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                            disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                            disp('File skipped.')
                            continue
                        end
                        disp(['File loaded! (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        
                        
                        % Remove unselected channels from the EEGpalCS Electrode Setting window
                        try
                            data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        catch
                        end
                        
                        
    %                     Channels            = app.SettingTable(:,[6 7 2]);
    %                     Channels.labels     = char(Channels.labels);
    %                     Channels.sph_theta  = -Channels.theta;
    %                     Channels.sph_phi    = (0.5 - Channels.radius) * 180;
    %                     Channels.X          = app.SettingTable.x;
    %                     Channels.Y          = app.SettingTable.y;
    %                     Channels.Z          = app.SettingTable.z;
    %                     Channels            = table2struct(Channels);
    %                     
    %                     EEG = eeglab_datastruct(openfilename,data,header.SamplingRate,[],Channels);
    %                     EEG = trimOutlier(EEG, app.sdOutliers_LOD.Value, app.tO_SdUpperBound.Value, 100, 1);
                        
    %                     % =======================================================
    %                     % Lines from eeglab plugin trimOutlier.m (adapted)
    %                     % =======================================================
    %                     % remove bad channels with SD
    %                     stdAllPnts  = std(data(:,:),0,1);
    %                     badChanMask = (stdAllPnts < app.sdOutliers_LOD.Value) | (stdAllPnts > app.tO_SdUpperBound.Value);
    %                     % =======================================================
                        
    %                     ColumnNames = ['%', cellstr(strcat(app.SettingTable.labels, ' (',num2str(app.SettingTable.nr),')'))'];
    %                     app.Out_BadChannels = table('Size',[height(app.DataTable) length(ColumnNames)],...
    %                         'VariableTypes',[{'double'}, cellstr(repmat('logical',length(ColumnNames)-1,1))'],'VariableNames',ColumnNames);
                        
                        badChanMask = flagOutliers(data,app.sdOutliers_LOD.Value);
                        
                        app.Out_BadChannels{file,2:end} = badChanMask;
                        PercentInterp = 100 * (sum(badChanMask,2) / length(badChanMask));
                        app.Out_BadChannels{file,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                        
                        % File completed
                        tMid = toc(tStart);
                        disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                        disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                        disp('...............................................................')
                    end
                    
                    disp('Finished :)')
                    EndTime = datetime('now');
                    tEnd = toc(tStart);
                    disp(['Start Time: ' datestr(StartTime)])
                    disp(['End Time: ' datestr(EndTime)])
                    disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
                    
                    
                    % if strcmp(app.sdOutliersSwitch.Value,'Yes') && strcmp(app.CleanRawDataSwitch.Value,'Yes')
                    %     app.InterpolationTable{:,2:end} = logical(app.Out_BadChannels{:,2:end} + app.CRD_BadChannels{:,2:end});
                    % elseif strcmp(app.sdOutliersSwitch.Value,'Yes')
                    %     app.InterpolationTable = app.Out_BadChannels;
                    % end
                    % app.ShowTable           = updateShowTable(app,app.ShowTable);
                    % app.InterpolationTable  = updateInterpTable(app,app.InterpolationTable);
                    app.Out_Recompute       = 'no';
                end
            
            % elseif strcmp(app.sdOutliersSwitch.Value,'No')
            %     if strcmp(app.CleanRawDataSwitch.Value,'Yes')
            %         app.InterpolationTable  = app.CRD_BadChannels;
            %         app.ShowTable           = updateShowTable(app,app.ShowTable);
            %     else
            %         app.ShowTable.Data      = app.Manual_BadChannels;
            %     end
            end
            
            % Update table 
            updateInterpolationTable(app);
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Interpolation); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0 
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output folder selected','warn');
                    return
                end

                Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                'Hello, hello!',...
                'I''m ready to interpolate the selected channels. Are you?', ...
                'Cluster on which re-referencing will be applied: ',app.ClusterDropDown.Value,...
                'Number of files: ',height(app.DataTable),...
                'A long list of files might take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            
                if strcmpi(Run,'Cancel')
                    return
                end
            else % case of Batch
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the Interpolation module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end

            end
            
            %user input for safety. Can be removed when I will sure about
            %coordinates conversion
            % if strcmp(app.InterpolationAlgo.Value,'Spherical')
            %     defaultLocation=strcat(app.EEGpalCS_Main.AppPath.value, '\Resources');
            %     [fileLoc,pathLoc] = uigetfile('*.locs','Select the EEGlab equivalent of coordinate file *.loc (for example biosemi64AB.locs located in the folder *\eeglab_plugins\coordinateFile\ from your EEGpal directory', defaultLocation); %coordinate file
            %     drawnow; figure(app.EEGpalCS_Interpolation); % Restaure the app module
            % end


            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Interpolation_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Interpolation Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            if strcmp(app.InterpolationAlgo.Value,'Spherical')
                fprintf(PifFileID,'%s\t%f\r\n','Current version of eeglab:',app.EEGpalCS_Main.Versions.eeglab);
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            if strcmp(app.InterpolationAlgo.Value,'Spherical')
                fprintf(PifFileID,'%s\t\t%s\r\n','Algo use for interpolation:','Spherical interpolation of eeglab');
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Algo use for interpolation:','3D Spline (perrin) using Mike X Cohen script (https://github.com/mikexcohen/AnalyzingNeuralTimeSeries/blob/main/interpolate_perrinX.m)');
            end
             if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Bad electrodes selection:',app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            fprintf(PifFileID,'%s\t\t\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
            fprintf(PifFileID,'%s\t%s\r\n','Scalp cluster for interpolation:',app.ClusterDropDown.Value);
            %Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
            Included = [app.SessionParameters.Electrodes.SettingTable.include{:}]';
            if strcmp(app.ClusterDropDown.Value,'all')
                %Scalp = app.SessionParameters.Electrodes.SettingTable.labels;
                Scalp = true(height(app.SessionParameters.Electrodes.SettingTable),1);
            else
                %Scalp = app.SessionParameters.Electrodes.SettingTable.labels(contains(app.SessionParameters.Electrodes.SettingTable.cluster,app.ClusterDropDown.Value),:);
                Scalp = contains(app.SessionParameters.Electrodes.SettingTable.cluster,app.ClusterDropDown.Value);
            end
            IncludedScalp = [app.SessionParameters.Electrodes.SettingTable.labels(min(Included,Scalp),:)';app.SessionParameters.Electrodes.SettingTable.cluster(min(Included,Scalp),:)'];
            fprintf(PifFileID,'%s\r\n','Scalp channels used for interpolation (Channel  /  Cluster):');
            fprintf(PifFileID,'\t\t%s\t\t%s\r\n',IncludedScalp{:});
            RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
            RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
            Removed = [RemovedChannels';RemovedCluster'];
            fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
            if isempty(Removed)
                nChan = app.DataTable.nChan(1);
                if nChan > height(app.SessionParameters.Electrodes.SettingTable) % Extra channels not added to the electrode setting
                    fprintf(PifFileID,'\t\t%s\r\n',string(height(app.SessionParameters.Electrodes.SettingTable)+1:nChan));
                else
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                end
            else
                fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
            end
            if length(unique(app.DataTable.nChan)) > 1
                fprintf(PifFileID,'\t\t%s\r\n','NOTE: number of channels inconsistent between files. The information above are based on the first file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','BAD CHANNELS IDENTIFICATION');
            if strcmp(app.BridgesSwitch.Value,'No') && strcmp(app.CleanRawDataSwitch.Value,'No')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Bad channels selection:','Manual');
            elseif strcmp(app.BridgesSwitch.Value,'Yes') && strcmp(app.CleanRawDataSwitch.Value,'No')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Bad channels selection:','Remove bridges channels detected by ebridge (eeglab plugin)');
            elseif strcmp(app.BridgesSwitch.Value,'No') && strcmp(app.CleanRawDataSwitch.Value,'Yes')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Bad channels selection:','Automatic: CleanRawData (eeglab plugin)');
            elseif strcmp(app.BridgesSwitch.Value,'Yes') && strcmp(app.CleanRawDataSwitch.Value,'Yes')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Bad channels selection:','Remove bridges channels detected by ebridge (eeglab plugin) + Automatic: CleanRawData');
            end
            
            try
                % if criterion not used, then values is 'off'
                if ischar(app.SessionParameters.Filtering.CleanRawData.FlatlineCriterion)
                    fprintf(PifFileID,'\t%s\t\t\t%s\r\n','CleanRawData flat line criterion:',app.SessionParameters.Filtering.CleanRawData.FlatlineCriterion);
                else
                    fprintf(PifFileID,'\t%s\t\t\t%g\r\n','CleanRawData flat line criterion:',app.SessionParameters.Filtering.CleanRawData.FlatlineCriterion);
                end
                if ischar(app.SessionParameters.Filtering.CleanRawData.LineNoiseCriterion)
                    fprintf(PifFileID,'\t%s\t\t\t%s\r\n','CleanRawData line noise criterion:',app.SessionParameters.Filtering.CleanRawData.LineNoiseCriterion);
                else
                    fprintf(PifFileID,'\t%s\t\t\t%g\r\n','CleanRawData line noise criterion:',app.SessionParameters.Filtering.CleanRawData.LineNoiseCriterion);
                end
                if ischar(app.SessionParameters.Filtering.CleanRawData.ChannelCriterion)
                    fprintf(PifFileID,'\t%s\t%s\r\n','CleanRawData channel correlation criterion:',app.SessionParameters.Filtering.CleanRawData.ChannelCriterion);
                else
                    fprintf(PifFileID,'\t%s\t%g\r\n','CleanRawData channel correlation criterion:',app.SessionParameters.Filtering.CleanRawData.ChannelCriterion);
                end
            catch                
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            % Save Table as .csv
            writetable(app.ShowTable.Data,fullfile(app.EEGpalCS_Main.RootFolder.Value{:},[app.EEGpalCS_Main.StudyName.Value '_InterpolationTable.csv']))
            
            
            %% RUN INTERPOLATION
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Interpolation...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Interpolating.....');
            nbFile= height(app.DataTable);
            
            % Prepare electrode setting
            allElectrodes = app.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
            scalpElectrodes = allElectrodes;
            if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                toDelete = logical(~strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value) + ~[allElectrodes.include{:}]');
                scalpElectrodes(toDelete,:) = [];
            end
            
            x = scalpElectrodes.x;
            y = scalpElectrodes.y;
            z = scalpElectrodes.z;
                        
            
            % Interpolation file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Interpolating File ',num2str(file),'/',num2str(nbFile)));
                bad_electrodes = find(app.ShowTable.Data{file,4:end});
                interpolatedChannel{file,1}=num2str(bad_electrodes);
                
                
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp(['File loaded (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                nChan = size(data,2);
                
                
                % Check channel size
                if ~app.InterpolateMissing.Value && nChan ~= height(allElectrodes)
                    disp('Number of channels in file don''t match the electrode setting file')
                    disp(['Number of channels in file: ',num2str(nChan)])
                    disp(['Number of electrodes in the setting file: ',num2str(height(allElectrodes))])
                    if nChan < height(allElectrodes)
                        %disp('Consider checking the ''Interpolate missing channels'' option')
                    elseif nChan > height(allElectrodes)
                        disp('Maybe you need to uncheck some electrodes in the ''Electrode Setting'' module')
                    end
                    disp('File skipped.')
                    continue
                end
                
                % Remove channels from non-scalp clusters
                if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                    InterpData = data(:,strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value));
                else
                    InterpData = data;
                end
                    
                %Selection of the algo of interpolation
                 if strcmp(app.InterpolationAlgo.Value,'3D_Spline') %interpolation Mike X Cohen (3D_Spline)       
                    
                     % Interpolation
                    if isempty(bad_electrodes) %Files with no channels to interpolate
                        disp(['No electrodes to interpolate for: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        % Commented, in order to have the file renamed and maybe the extension changed
    %                     disp('File skipped.')
    %                     continue
                        disp('The file will nonetheless be rename with the suffix string and saved in the required format')
                        disp('You''re welcome!')
                    else
                        disp(['Interpolating file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                        InterpData = interpolate_perrinX(InterpData',x,y,z,bad_electrodes); %Interpolation !!!!!!!!!!
                        InterpData = InterpData';
                        disp('Interpolation completed')
                    end
                    
                    
                    % Replace unselected channels from ChanCompTable (unprocessed)
                    if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                        UnprocessedIndices = allElectrodes.nr(~strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value));
                        for i = 1:length(UnprocessedIndices)
                            if UnprocessedIndices(i) == 1
                                InterpData = [data(:,1), InterpData];
                            elseif UnprocessedIndices(i) > size(InterpData,2)
                                InterpData = [InterpData, data(:,UnprocessedIndices(i))];
                            else
                                InterpData = [InterpData(:,1:UnprocessedIndices(i)-1), data(:,UnprocessedIndices(i)), InterpData(:,UnprocessedIndices(i):end)];
                            end
                        end
                    end
                
                 else %interpolation eeglab (spherical)
                     
                    %convert into an eeglab structure (.set)
                    ALLEEG=struct; EEG=struct;CURRENTSET=0;
                    
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    EEG = eeglab_datastruct(openfilename,InterpData,header.SamplingRate,events,Channels);
                    
                    % %Loading coordinate in eeglab for safety ! Can we be deleted when I will be sure about coordinate conversion done by MichaelDP
                    % [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
                    % EEG = pop_editset(EEG, 'chanlocs', strcat(pathLoc,fileLoc));
                    % [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    
                    %interpolation
                    EEG = pop_interp(EEG, bad_electrodes, 'spherical');
                    
                    InterpData = EEG.data';
  
                 end
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename, '(file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,InterpData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                    Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    save_sef(savefilename,InterpData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    Channels            = ElectrodeSetting(:,[6 7 2]);
                    Channels.labels     = char(Channels.labels);
                    Channels.sph_theta  = -Channels.theta;
                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                    Channels.X          = ElectrodeSetting.x;
                    Channels.Y          = ElectrodeSetting.y;
                    Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                    Channels            = table2struct(Channels);
                    save_eeglab(savefilename,InterpData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(InterpData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(InterpData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(InterpData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  % Interpolation  /  N. channels  /  Channel interpolated  /  Folder)');
            
            app.InterpolationTable=updateInterpTable(app,app.InterpolationTable);
            Files = [app.DataTable.File';num2cell(app.InterpolationTable{:,'%'})';num2cell(app.DataTable.(3))';interpolatedChannel';app.DataTable.Path'];

            fprintf(PifFileID,'%s\t\t%s\t\t%d\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Interpolation processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('Interpolation done.');
            else %batch mode module successly completed                
                if or(app.batchHasCrashed,strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Manual Bad Electrodes Inspection')) 
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end

        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.InterpolationButton.Enable = 'on';
            app.EEGpalCS_Epoch.InterpolationButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end
            
            % Update parameters
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.InterpolationButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: ShowTable
        function ShowTableCellEdit(app, event)
            app.Manual_BadChannels{event.Indices(1),event.Indices(2)} = event.NewData;
            try
                if event.NewData == 0 && app.eBridge_BadChannels{event.Indices(1),event.Indices(2)-2} == 1
                    app.BridgesSwitch.Value = 'No';
                end
            catch
            end
            try
                if event.NewData == 0 && app.CRD_BadChannels{event.Indices(1),event.Indices(2)-2} == 1
                    app.CleanRawDataSwitch.Value = 'No';
                end
            catch
            end
            % if event.NewData == 0 && app.Out_BadChannels{event.Indices(1),event.Indices(2)-2} ==1
            %     app.sdOutliersSwitch.Value = 'No';
            % end

            PercentInterp                   = 100 * sum(app.ShowTable.Data{:,4:end},2) / size(app.ShowTable.Data{:,4:end},2);
            app.ShowTable.Data{:,3}         = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
            app.InterpolationTable          = updateInterpTable(app,app.InterpolationTable); % Update InterpolationTable
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            [FileData,PathData] = uiputfile({'*.xlsx';'*.csv'},'Save Interpolation table',[app.EEGpalCS_Main.StudyName.Value '_InterpolationTable']);
            drawnow; figure(app.EEGpalCS_Interpolation); % Restaure the app module
            
            if ~isequal(FileData,0)
                T = app.ShowTable.Data;
                % Conversion des colonnes logiques (true/false) en double (1/0)
                for i = 1:width(T)
                    if islogical(T.(i))
                        T.(i) = double(T.(i));
                    end
                end
                writetable(T,fullfile(PathData,FileData))
            end
        end

        % Close request function: EEGpalCS_Interpolation
        function EEGpalCS_InterpolationCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.InterpolationButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: sdOutliers_LOD
        function sdOutliers_LODValueChanged(app, event)
            app.Out_Recompute = 'yes';
        end

        % Button pushed function: UploadTableButton
        function UploadTableButtonPushed(app, event)
            %[FileData,PathData] = uigetfile({'*.xlsx';'*.csv'},'Select the Interpolation table to load');
            [FileData, PathData] = uigetfile({'*.xlsx;*.csv', 'Excel or CSV files (*.xlsx, *.csv)'}, ...
                                  'Select the Interpolation table to load');
            drawnow; figure(app.EEGpalCS_Interpolation); % Restaure the app module
            
            try
                if ~isequal(FileData,0)
                    FileName            = fullfile(PathData,FileData);
        %             opts                = detectImportOptions(FileName);
        %             opts.VariableTypes(4:end) = cellstr(repmat('logical',length(opts.VariableTypes)-3,1));
        %             uploadTable         = readtable(FileName,opts);
                    uploadTable         = readtable(FileName);
                    
        %             % Check file
        %             
        %             if width(uploadTable) ~= width(app.ShowTable.Data)
        %                 lenCol = questdlg(sprintf('%s\n\n%s',...
        %                     'Problem with file size.',...
        %                     'Maybe you want to select files before going to the Interpolation module.'),...
        %                     'Exit','PROCEED ANYWAY','Cancel','Cancel');
        %                 
        %                 if strcmpi(lenCol,'Cancel')
        %                     return
        %                 end
        %             end
        %             
                    %%%%%%%%%%%%%%%%%%%%%%
                    %% CHECK NUMBER OF FILES
                    %%%%%%%%%%%%%%%%%%%%%%
                    if ~isempty(app.DataTable)
                        % Check correspondance of file names between Data Table and uploaded table
                        if ~isequal(app.DataTable.File,uploadTable.File)
                            msgbox(sprintf('%s\n\n%s\n%s','File names don''t match.', ...
                                'File names of uploaded table don''t match the file names in the main window',...
                                'Please check!'),...
                                'File names don''t match','warn');
                            return
                        end
                        nRows = height(app.DataTable);
                        labels      = app.SettingTable.labels(cell2mat(app.SettingTable.include));
                        nr          = app.SettingTable.nr(cell2mat(app.SettingTable.include));
                        ColumnNames = ['%', cellstr(strcat(labels, ' (',num2str(nr),')'))'];
                        app.InterpolationTable = table('Size',[nRows length(ColumnNames)],...
                            'VariableTypes',[{'string'}, cellstr(repmat('logical',length(ColumnNames)-1,1))'],'VariableNames',ColumnNames);
                        app.InterpolationTable(:,2:end) = array2table(logical(uploadTable{:,4:end}));
                    end
                    
                    % Check correspondance of channel names between electrode setting and uploaded table
                    if ~isequal(app.DataTable.File,uploadTable.File)
                        msgbox(sprintf('%s\n\n%s\n%s','File names don''t match.', ...
                            'File names of uploaded table don''t match the file names in the main window.',...
                            'Please check!'),...
                            'File names don''t match','warn');
                        return
                    end
        %             if ~isequal(ColumnNames,uploadTable.Properties.VariableNames)
        %                 ColNames = questdlg(sprintf('%s\n\n%s',...
        %                     'Column names don''t match.', ...
        %                     'Column names of uploaded table don''t match the channel names of the electrode setting.'),...
        %                     'Exit','PROCEED ANYWAY','Cancel','Cancel');
        %                 
        %                 if strcmpi(ColNames,'Cancel')
        %                     return
        %                 end
        %             end
                    
        
                    % Tables update
                    app.ShowTable.ColumnName = ...
                        [app.ShowTable.ColumnName(1:2); ColumnNames'];
                    app.ShowTable.ColumnWidth = ...
                        [app.ShowTable.ColumnWidth(1:3), repmat({50},1,length(ColumnNames)-1)];
                    app.ShowTable.ColumnEditable = ...
                        [app.ShowTable.ColumnEditable(1:3), true(1,length(ColumnNames)-1)];
                    if ~isempty(app.DataTable)
                        app.ShowTable.Data = [app.DataTable(:,[1 2]) app.InterpolationTable];
                    end
                                      
                    PercentInterp                   = 100 * sum(app.ShowTable.Data{:,4:end},2) / size(app.ShowTable.Data{:,4:end},2);
                    app.ShowTable.Data{:,3}         = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                    app.InterpolationTable{:,1}     = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);


                    %store the loaded table as in app.Manual_BadChannels
                    app.Manual_BadChannels{:,4:end} = logical(uploadTable{:,4:end});                    
                    app.sdOutliersSwitch.Value      = 'No';
                    app.CleanRawDataSwitch.Value    = 'No';
                    app.BridgesSwitch.Value         = 'No';
                end
            catch
                errordlg('This file does not contains interpolate table info !','Invalid File');
            end

        end

        % Button pushed function: LoadBridgeDetectionfileButton
        function LoadBridgeDetectionfileButtonPushed(app, event)
            nbFile = size(app.DataTable,1);
            
            % ask the user about the location of 
            [FileData,PathData] = uigetfile({'*.xlsx'},'Select the file generated by Bridge Detection module');
            drawnow; figure(app.EEGpalCS_Interpolation); % Restaure the app module
            if ~isequal(FileData,0)
                FileName            = fullfile(PathData,FileData);
    
                %read the eBridge table
                uploadTable         = readtable(FileName);
                
                % Check number of colomn           
                if width(uploadTable) ~= width(app.ShowTable.Data)
                    lenCol = questdlg(sprintf('%s\n\n%s',...
                        'The number of columns in the selected file is different from the table in the interpolation module',...
                        'Please check the file!'),...
                        'Exit','Cancel','Cancel');
                    
                    if strcmpi(lenCol,'Cancel')
                        return
                    end
                end
                
                % Check number of files
                if ~isempty(app.DataTable)
                    % Check correspondance of file names between Data Table and uploaded table
                    if ~isequal(nbFile,height(uploadTable))
                        msgbox(sprintf('%s\n\n%s\n%s','File number don''t match.', ...
                            'The file number of the uploaded table doesn''t match the file number in the main window.',...
                            'Please check!'),...
                            'File number don''t match','warn');
                        return
                    end
                    
                else
                    msgbox(sprintf('%s\n\n%s\n%s','No file specified', ...
                            'There is no files specified in the main window.',...
                            'Please check!'),...
                            'No file','warn');
                    return
                end
    
                % Update the tables of interpolation            
                app.eBridge_BadChannels{:,2:end}=logical(uploadTable{:,4:end});
                app.BridgesSwitch.Enable = "on";
                app.BridgesSwitch.Value = 'Yes';
                updateInterpolationTable(app);


                % for i=1:nbFile
                %     currentLine=[uploadTable{i,4:end}];                
                %     bridges=find(currentLine);              
                %     if ~isempty(bridges) %check if any bridge
                %         for j=1:length(bridges)
                %              app.InterpolationTable{i,bridges(j)+1}=true; %modify the interpolation table to interpolate bridge channels
                %              app.ShowTable.Data{i,bridges(j)+3}=true;
                %         end                     
                %     end               
                % end
                
                % %update table
                % PercentInterp                   = 100 * sum(app.ShowTable.Data{:,4:end},2) / size(app.ShowTable.Data{:,4:end},2);
                % app.ShowTable.Data{:,3}         = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                % app.InterpolationTable          = updateInterpTable(app,app.InterpolationTable); % Update InterpolationTable           
                
                rz = msgbox('Bridge Detection file loaded and automatically transfert to the interpolation table.');
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Interpolation_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Interpolation_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Interpolation_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: BridgesSwitch
        function BridgesSwitchValueChanged(app, event)
            updateInterpolationTable(app);           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Interpolation = uifigure('Visible', 'off');
            app.EEGpalCS_Interpolation.Name = 'EEGpal_Interpolation';
            app.EEGpalCS_Interpolation.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_InterpolationCloseRequest, true);
            app.INTERPOLATIONLabel = uilabel(app.EEGpalCS_Interpolation);
            app.INTERPOLATIONLabel.Text = 'INTERPOLATION';
            app.InterpolationInstruction = uilabel(app.EEGpalCS_Interpolation);
            app.InterpolationInstruction.Text = {'In the table, select the channels to '; 'interpolate for each file.'; 'Avoid interpolating more than 10% of the '; 'channels.'};
            app.ScalpelectrodesclusterDropDownLabel = uilabel(app.EEGpalCS_Interpolation);
            app.ScalpelectrodesclusterDropDownLabel.Text = 'Scalp electrodes cluster:';
            app.ClusterDropDown = uidropdown(app.EEGpalCS_Interpolation);
            app.ClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterDropDownValueChanged, true);
            app.InterpolatemissingchannelsLabel = uilabel(app.EEGpalCS_Interpolation);
            app.InterpolatemissingchannelsLabel.Enable = 'off';
            app.InterpolatemissingchannelsLabel.Visible = 'off';
            app.InterpolatemissingchannelsLabel.Text = 'Interpolate missing channels:';
            app.InterpolateMissing = uicheckbox(app.EEGpalCS_Interpolation);
            app.InterpolateMissing.Enable = 'off';
            app.InterpolateMissing.Visible = 'off';
            app.InterpolateMissing.Text = '';
            app.NumberofmissingchannelsLabel = uilabel(app.EEGpalCS_Interpolation);
            app.NumberofmissingchannelsLabel.Enable = 'off';
            app.NumberofmissingchannelsLabel.Visible = 'off';
            app.NumberofmissingchannelsLabel.Text = 'Number of missing channels:';
            app.NumberMissing = uieditfield(app.EEGpalCS_Interpolation, 'numeric');
            app.NumberMissing.Editable = 'off';
            app.NumberMissing.Enable = 'off';
            app.NumberMissing.Visible = 'off';
            app.BadChannelsDetection = uilabel(app.EEGpalCS_Interpolation);
            app.BadChannelsDetection.Text = 'Bad channels automatic detection';
            app.CleanRawDataSwitchLabel = uilabel(app.EEGpalCS_Interpolation);
            app.CleanRawDataSwitchLabel.Enable = 'off';
            app.CleanRawDataSwitchLabel.Text = 'CleanRawData';
            app.CleanRawDataSwitch = uiswitch(app.EEGpalCS_Interpolation, 'slider');
            app.CleanRawDataSwitch.Items = {'No', 'Yes'};
            app.CleanRawDataSwitch.ValueChangedFcn = createCallbackFcn(app, @CleanRawDataSwitchValueChanged, true);
            app.CleanRawDataSwitch.Enable = 'off';
            app.CleanRawDataSwitch.Tooltip = {'To decide which channels to interpolate, best is to look at the data (e.g., in Cartool).'; 'CleanRawData (needed to be selected in the Filtering+ window) may help detect potentially bad channels.'};
            app.CleanRawDataSwitch.Value = 'No';
            app.sdOutlierchannelsLabel = uilabel(app.EEGpalCS_Interpolation);
            app.sdOutlierchannelsLabel.Enable = 'off';
            app.sdOutlierchannelsLabel.Visible = 'off';
            app.sdOutlierchannelsLabel.Text = 'sdOutlier channels';
            app.sdOutliersSwitch = uiswitch(app.EEGpalCS_Interpolation, 'slider');
            app.sdOutliersSwitch.Items = {'No', 'Yes'};
            app.sdOutliersSwitch.ValueChangedFcn = createCallbackFcn(app, @sdOutliersSwitchValueChanged, true);
            app.sdOutliersSwitch.Enable = 'off';
            app.sdOutliersSwitch.Visible = 'off';
            app.sdOutliersSwitch.Tooltip = {'Channels with highly deviant standard deviation will be flagged as bad.'; 'Frontal channels might be falgged due to eye-blinks.'; 'To decide which channels to interpolate, best is to look at the data (e.g., in Cartool).'; 'This option may still help detect potentially bad channels.'};
            app.sdOutliersSwitch.Value = 'No';
            app.LevelofdecisionLabel = uilabel(app.EEGpalCS_Interpolation);
            app.LevelofdecisionLabel.Enable = 'off';
            app.LevelofdecisionLabel.Visible = 'off';
            app.LevelofdecisionLabel.Text = 'Level of decision:';
            app.sdOutliers_LOD = uieditfield(app.EEGpalCS_Interpolation, 'numeric');
            app.sdOutliers_LOD.ValueChangedFcn = createCallbackFcn(app, @sdOutliers_LODValueChanged, true);
            app.sdOutliers_LOD.Enable = 'off';
            app.sdOutliers_LOD.Visible = 'off';
            app.sdOutliers_LOD.Tooltip = {'- 3 (very conservative)'; '- 2.5 (moderately conservative)'; '- 2 (poorly conservative)'; 'Other values are available!'};
            app.sdOutliers_LOD.Value = 3;
            app.sdOutliersInstruction = uilabel(app.EEGpalCS_Interpolation);
            app.sdOutliersInstruction.Enable = 'off';
            app.sdOutliersInstruction.Visible = 'off';
            app.sdOutliersInstruction.Text = {'sdOutliers are identified based on the median '; 'absolute deviation (Leys et al., 2013)'};
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_Interpolation);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_Interpolation);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_Interpolation);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_Interpolation);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_Interpolation);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_Interpolation);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.EEGpalCS_Interpolation);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.EEGpalCS_Interpolation, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'interpolated';
            app.APPLYLabel = uilabel(app.EEGpalCS_Interpolation);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.ShowTable = uitable(app.EEGpalCS_Interpolation);
            app.ShowTable.ColumnName = {'File'; 'Path'; '%'; 'Electrodes'};
            app.ShowTable.RowName = {};
            app.ShowTable.ColumnEditable = [false false false true];
            app.ShowTable.CellEditCallback = createCallbackFcn(app, @ShowTableCellEdit, true);
            app.Label = uilabel(app.EEGpalCS_Interpolation);
            app.Label.Text = {'The table will be automatically saved as a .csv when you press ''Run''.'; 'Otherwise, you can save it manually as a .csv or .xlsx.'};
            app.SaveTableButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save table as...';
            app.UploadTableButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.UploadTableButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTableButtonPushed, true);
            app.UploadTableButton.Text = 'Upload table';
            app.BridgeDetection = uilabel(app.EEGpalCS_Interpolation);
            app.BridgeDetection.Text = 'Bridges Detection';
            app.InterpolationAlgoTitle = uilabel(app.EEGpalCS_Interpolation);
            app.InterpolationAlgoTitle.Text = 'Interpolation Algorithm';
            app.CartoolMatlabLabel = uilabel(app.EEGpalCS_Interpolation);
            app.CartoolMatlabLabel.Text = 'Cartool/Matlab';
            app.InterpolationAlgo = uiswitch(app.EEGpalCS_Interpolation, 'slider');
            app.InterpolationAlgo.Items = {'3D_Spline', 'Spherical'};
            app.InterpolationAlgo.Tooltip = {'Select the algorithm to be used for the interpolation.'};
            app.InterpolationAlgo.Value = '3D_Spline';
            app.LoadBridgeDetectionfileButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.LoadBridgeDetectionfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBridgeDetectionfileButtonPushed, true);
            app.LoadBridgeDetectionfileButton.Text = 'Load Bridge Detection file';
            app.HelpButton = uibutton(app.EEGpalCS_Interpolation, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.BridgesSwitch = uiswitch(app.EEGpalCS_Interpolation, 'slider');
            app.BridgesSwitch.Items = {'No', 'Yes'};
            app.BridgesSwitch.ValueChangedFcn = createCallbackFcn(app, @BridgesSwitchValueChanged, true);
            app.BridgesSwitch.Enable = 'off';
            app.BridgesSwitch.Value = 'No';
            app.orLabel = uilabel(app.EEGpalCS_Interpolation);
            app.orLabel.Text = 'or';
            % Show the figure after all components are created
            app.EEGpalCS_Interpolation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Interpolation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Interpolation)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Interpolation)
        end
    end
end