classdef EEGpalCS_mod_Rereference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Rereference         matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        RetainButton                 matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        APPLYLabel                   matlab.ui.control.Label
        ParameterFilePath            matlab.ui.control.EditField
        ParameterfileEditFieldLabel  matlab.ui.control.Label
        SaveAsButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        InputParametersFile          matlab.ui.control.Label
        SuffixString                 matlab.ui.control.EditField
        SuffixstringLabel            matlab.ui.control.Label
        SaveFolder                   matlab.ui.control.TextArea
        SavingfolderLabel            matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        SelectUpperFolderLabel       matlab.ui.control.Label
        FilesExtension               matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel           matlab.ui.control.Label
        ClusterDropDown              matlab.ui.control.DropDown
        ScalpelectrodesclusterDropDownLabel  matlab.ui.control.Label
        ReferenceSelection           matlab.ui.control.ListBox
        NewreferenceListBoxLabel     matlab.ui.control.Label
        REREFERENCELabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)
        
        function applyStudyParam(app)
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.Study_Parameters.Rereference.FilesExtention;
            app.SaveFolder.Value            = app.Study_Parameters.Rereference.SaveFolder;
            app.SuffixString.Value          = app.Study_Parameters.Rereference.SuffixString;
            
            
            % REREFERENCE
            
            % Reference selection
            app.ReferenceSelection.Items    = app.Study_Parameters.Rereference.ReferenceItems;
            app.ReferenceSelection.Value    = app.Study_Parameters.Rereference.ReferenceValue;
            
            % Electrodes cluster
            app.ClusterDropDown.Items       = app.Study_Parameters.Rereference.ClusterItems;
            app.ClusterDropDown.Value       = app.Study_Parameters.Rereference.ClusterValue;
            % -> try with the interpolation cluster, or better, select
            %    scalp electrodes in the Electrodes window
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            
            % OUTPUT OPTION
            Study_Parameters.Rereference.FilesExtention = app.FilesExtension.Value;
            Study_Parameters.Rereference.SaveFolder     = app.SaveFolder.Value;
            Study_Parameters.Rereference.SuffixString   = app.SuffixString.Value;
            
            
            % REREFERENCE
            
            % Reference selection
            Study_Parameters.Rereference.ReferenceItems = app.ReferenceSelection.Items;
            Study_Parameters.Rereference.ReferenceValue = app.ReferenceSelection.Value;
            
            % Electrodes cluster
            Study_Parameters.Rereference.ClusterItems   = app.ClusterDropDown.Items;
            Study_Parameters.Rereference.ClusterValue   = app.ClusterDropDown.Value;
        end
        
        function applySessionParam(app)
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.SessionParameters.Rereference.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.Rereference.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.Rereference.SuffixString;
            
            
            % REREFERENCE
            
            % Reference selection
            app.ReferenceSelection.Items    = app.SessionParameters.Rereference.ReferenceItems;
            app.ReferenceSelection.Value    = app.SessionParameters.Rereference.ReferenceValue;
            
            % Electrodes cluster
            app.ClusterDropDown.Items       = app.SessionParameters.Rereference.ClusterItems;
            app.ClusterDropDown.Value       = app.SessionParameters.Rereference.ClusterValue;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            
            % OUTPUT OPTION
            SessionParameters.Rereference.FilesExtention    = app.FilesExtension.Value;
            SessionParameters.Rereference.SaveFolder        = app.SaveFolder.Value;
            SessionParameters.Rereference.SuffixString      = app.SuffixString.Value;
            
            
            % REREFERENCE
            
            % Reference selection
            SessionParameters.Rereference.ReferenceItems    = app.ReferenceSelection.Items;
            SessionParameters.Rereference.ReferenceValue    = app.ReferenceSelection.Value;
            
            % Electrodes cluster
            SessionParameters.Rereference.ClusterItems      = app.ClusterDropDown.Items;
            SessionParameters.Rereference.ClusterValue      = app.ClusterDropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            try
                app.SettingTable            = app.SessionParameters.Electrodes.SettingTable;
            catch
            end
            
            
            % Saved study parameters
            try
               applyStudyParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.Study_Parameters.Files.SaveFolder;
                catch
                end
            end
            
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
                try
                    ActiveElectrodes                = app.SettingTable.labels([app.SettingTable.include{:}]); % Selected electrodes only
                    app.ReferenceSelection.Items    = [{'Average'};ActiveElectrodes];
                    
                    ActiveClusters                  = app.SettingTable.cluster([app.SettingTable.include{:}]); % Clusters of selected electrodes only
                    app.ClusterDropDown.Items       = [{'all'};unique(ActiveClusters,'stable')];
                catch
                    app.ReferenceSelection.Items    = [{'Average'}; num2cell(1:64)'];
                    app.ClusterDropDown.Items       = 'all';
                end
                app.ReferenceSelection.Value        = 'Average';
                app.ClusterDropDown.Value           = 'all';
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app)
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            if length(app.ReferenceSelection.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                msgbox(sprintf('%s\n\n%s','Average reference selected with another channel!', ...
                    'I don''t think it makes sense. I will not let you do that!'),...
                    'Average reference selected with another channel','warn');
                return
            end
            
            if logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) &&...
                    strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                AvgWarn = questdlg(sprintf('%s\n\n%s',...
                    'Average reference selected while you have more than one cluster in your electrode setting',...
                    'Are you sure that''s what you want to do?'),'Average reference warning','OK','Cancel','Cancel');
                if strcmpi(AvgWarn,'Cancel')
                    return
                end
            end
            
            if strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                ClustWarn = questdlg(sprintf('%s\n\n%s',...
                    'You have selected all clusters while you have more than one cluster in your electrode setting',...
                    'Are you sure that''s what you want to do?'),'Clusters warning','OK','Cancel','Cancel');
                if strcmpi(ClustWarn,'Cancel')
                    return
                end
            end
            
            Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                'Greetings Earthling!',...
                'Me about to re-reference the data. Ready for the ride?',...
                'Cluster on which re-referencing will be applied: ',app.ClusterDropDown.Value,...
                'Number of files: ',height(app.DataTable),...
                'A long list may take time to proceed (though not that much, let''s be honnest). In order to reduce time, you can select subsets of files in the main window.',...
                'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Rereference_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Re-reference Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','REFERENCE');
            if length(app.ReferenceSelection.Value) > 1
                fprintf(PifFileID,'%s\r\n','New reference:');
                fprintf(PifFileID,'\t\t%s\r\n',app.ReferenceSelection.Value{:});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','New reference:',app.ReferenceSelection.Value{:});
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            fprintf(PifFileID,'%s\t\t\t%s\r\n','Electrodes cluster:',app.ClusterDropDown.Value);
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
                Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                fprintf(PifFileID,'%s\r\n','Channels left in file:');
                fprintf(PifFileID,'\t\t%s\r\n',Included{:});
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN RE-REFERNCE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Re-reference...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Re-referencing.....');
            nbFile= height(app.DataTable);
            
            % Re-reference file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Re-referencing ',num2str(file),'/',num2str(nbFile)));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                                
                % Remove channels from non-scalp clusters
                if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
%                     NewRefData = data(:,strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value));
                    ScalpElectrodes = app.SettingTable.labels(strcmp(app.SettingTable.cluster,app.ClusterDropDown.Value)); % Selected electrodes only
                else
%                     NewRefData = data;
                    ScalpElectrodes = app.SettingTable.labels; % Selected scalp electrodes only
                end
                
                % Re-reference
                disp(['Re-referencing file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                if logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                    ref = 'avg';
                else
                    ref = find(contains(app.SettingTable.labels([app.SettingTable.include{:}]),app.ReferenceSelection.Value));
                end
                if ~logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) && ...
                        ~logical(sum(contains(app.ReferenceSelection.Value,ScalpElectrodes))) % The new reference is not within the scalp cluster
                    aux = 'aux'; % indicate that the reference is an auxiliary electrode (e.g., earlobes)
                else
                    aux = [];
                end
                NewRefData = ref_change(data,ref,aux);
                disp('Re-referencing completed')
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if strcmpi(aux,'aux')
                    AuxIndices = ~strcmpi(app.SettingTable.cluster,app.ClusterDropDown.Value);
                    for i = 1:length(AuxIndices)
                        if AuxIndices(i) == 1
                            NewRefData = [data(:,1), NewRefData];
                        elseif AuxIndices(i) > size(NewRefData,2)
                            NewRefData = [NewRefData, data(:,AuxIndices(i))];
                        else
                            NewRefData = [NewRefData(:,1:AuxIndices(i)-1), data(:,AuxIndices(i)), NewRefData(:,AuxIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewRefData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewRefData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewRefData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewRefData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewRefData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE DATA TABLE IN MAIN WINDOW
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewRefData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Re-reference processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            rz = msgbox('Rereferencing done.');
        end

        % Button pushed function: RetainButton
        function RetainButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Rereference
        function EEGpalCS_RereferenceCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: ClusterDropDown
        function ClusterDropDownValueChanged(app, event)
            value = app.ClusterDropDown.Value;
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_ReReferencing_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Rereference = uifigure('Visible', 'off');
            app.EEGpalCS_Rereference.Name = 'EEGpal_Re-reference';
            app.EEGpalCS_Rereference.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_RereferenceCloseRequest, true);
            app.REREFERENCELabel = uilabel(app.EEGpalCS_Rereference);
            app.REREFERENCELabel.Text = 'RE-REFERENCE';
            app.NewreferenceListBoxLabel = uilabel(app.EEGpalCS_Rereference);
            app.NewreferenceListBoxLabel.Text = {'New reference:'; ''; 'Ctrl+click to select more'; 'than one channel. The'; 'reference will be the'; 'average of the selected '; 'channels.'; 'The average reference '; 'shouldn''t be selected'; 'with other channels.'};
            app.ReferenceSelection = uilistbox(app.EEGpalCS_Rereference);
            app.ReferenceSelection.Multiselect = 'on';
            app.ReferenceSelection.Value = {'Item 1'};
            app.ScalpelectrodesclusterDropDownLabel = uilabel(app.EEGpalCS_Rereference);
            app.ScalpelectrodesclusterDropDownLabel.Text = 'Scalp electrodes cluster:';
            app.ClusterDropDown = uidropdown(app.EEGpalCS_Rereference);
            app.ClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterDropDownValueChanged, true);
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_Rereference);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_Rereference);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_Rereference);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.EEGpalCS_Rereference);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.EEGpalCS_Rereference, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'rereferenced';
            app.InputParametersFile = uilabel(app.EEGpalCS_Rereference);
            app.InputParametersFile.Text = 'Save interpolation options';
            app.OpenButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Rereference options.'};
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Create a new file.'; 'Will erase data if file already exists.'};
            app.SaveAsButton.Text = 'Save as';
            app.ParameterfileEditFieldLabel = uilabel(app.EEGpalCS_Rereference);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.EEGpalCS_Rereference, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.APPLYLabel = uilabel(app.EEGpalCS_Rereference);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.RetainButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.RetainButton.ButtonPushedFcn = createCallbackFcn(app, @RetainButtonPushed, true);
            app.RetainButton.Tooltip = {'Return to the main window'};
            app.RetainButton.Text = 'Retain';
            app.CancelButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            % Show the figure after all components are created
            app.EEGpalCS_Rereference.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Rereference(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Rereference)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Rereference)
        end
    end
end