classdef EEGpalCS_mod_Statistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_Statistics               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AveragetimeCheckBox             matlab.ui.control.CheckBox
        InstructElements1               matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        SamplingRate                    matlab.ui.control.NumericEditField
        SamplingRateinHzEditFieldLabel  matlab.ui.control.Label
        ChannelsEditField               matlab.ui.control.EditField
        ChannelsEditFieldLabel          matlab.ui.control.Label
        IntervalOffset_ms               matlab.ui.control.NumericEditField
        IntervaloffsetLabel             matlab.ui.control.Label
        IntervalOnset_ms                matlab.ui.control.NumericEditField
        IntervalonsetLabel              matlab.ui.control.Label
        Instruct_timeinter1             matlab.ui.control.Label
        GMDCheckBox                     matlab.ui.control.CheckBox
        GFPCheckBox                     matlab.ui.control.CheckBox
        AverageCheckBox                 matlab.ui.control.CheckBox
        Epoch_RelativeInterval1_3       matlab.ui.control.Label
        EndOfFile                       matlab.ui.control.StateButton
        IntervalOffset_tf               matlab.ui.control.NumericEditField
        IntervalOnset_tf                matlab.ui.control.NumericEditField
        tfLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        Epoch_RelativeInterval1_2       matlab.ui.control.Label
        Epoch_RelativeInterval1         matlab.ui.control.Label
        InstructModule                  matlab.ui.control.Label
        StatisticsmoduleLabel           matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        designDescription               matlab.ui.control.Label
        Epoch_RelativeInterval1_4       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        OutputformatDropDown            matlab.ui.control.DropDown
        OutputformatDropDownLabel       matlab.ui.control.Label
        OptionalSuffix                  matlab.ui.control.EditField
        OptionalsuffixinoutputfilenameEditFieldLabel  matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        SavingfolderLabel               matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        APPLYLabel                      matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        InstructionFolderLabel          matlab.ui.control.Label
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)


        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
       
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
        
        function [AmpValue,PosValue,checkValue] = peakDetection(app,data,onset_TF,offset_TF,channel,GfpGmd)
            
            %Function to detect peaks
            
            %detection of peaks
            if or(strcmp (app.PeakDropDown.Value,'Positive'),GfpGmd) %variable GfpGmd permit to force the detecton of Max pour GFP and GMD                    
                [peakValue,peakPos]= findpeaks(data(onset_TF+1:offset_TF+1,channel),onset_TF+1:offset_TF+1); %positive peak
            else
                [peakValue,peakPos]= findpeaks(-data(onset_TF+1:offset_TF+1,channel),onset_TF+1:offset_TF+1); %negative peak
                peakValue=-peakValue;
            end
             
            %Management of special case             
            if isempty(peakValue) %no peak detected
                checkValue=1;
                AmpValue=[];
                PosValue=[];
                
            elseif length(peakValue)>1 %several peak detected
                checkValue=2;
                
                %save gobal amplitude
                if or(strcmp (app.PeakDropDown.Value,'Positive'),GfpGmd) 
                    AmpValue=max(peakValue); 
                else
                    AmpValue=min(peakValue); 
                end
                
                %save position in ms of TF    
                if strcmp(app.SelectOutputTime.Value,'in ms') 
                     PosValue=tf2ms(app,peakPos(find(peakValue==AmpValue))-1,app.SamplingRate.Value);
                else
                     PosValue=peakPos(find(peakValue==AmpValue))-1;
                end

            else %normal case
                checkValue=0;
                
                %save amplitude
                AmpValue=peakValue; 
                
                %save position in ms of TF
                if strcmp(app.SelectOutputTime.Value,'in ms') 
                    PosValue=tf2ms(app,peakPos-1,app.SamplingRate.Value);
                else
                    PosValue=peakPos-1;
                end
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            %Sampling Rate
            app.SamplingRate.Value          = app.DataTable.SamplingRate(1);

            %Check the file format and apply the specific GUI
            [~,~,FileExtension] = fileparts(app.DataTable.File(1));
            if strcmp(FileExtension, '.freq')
                 app.OutputformatDropDown.Items=[{'.freq'} {'.csv'}];
                 app.OutputformatDropDown.Value='.freq';
                 app.GFPCheckBox.Enable="off";
                 app.GMDCheckBox.Enable="off";
            elseif strcmp(FileExtension, '.ris')
                 app.GFPCheckBox.Enable="off";
                 app.GMDCheckBox.Enable="off";
            end

            %Reminder of the design
            disp('a')


        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_Statistics); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %number of files
            [nbFiles,~]=size(app.DataTable); 
            
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','error');
                return
            end
            
            if app.SamplingRate.Value == 1 || app.SamplingRate.Value == 0 
                 msgbox(sprintf('%s\n\n%s','Missing Sampling rate!', ...
                        'The sampling rate is not specified. Please specify it before to continue'),...
                        'Missing Sampling rate','error');
                 return     
            end
            
            if and (app.IntervalOffset_tf.Value == 0, strcmp(app.ProcessingChoice.Value, 'Peaks detection'))
                 msgbox(sprintf('%s\n\n%s','Incorrect interval!', ...
                        'Interval is incorrect. Please specify on offset value'),...
                        'Incorrect Interval','error');
                        return 
            end
            
            if  app.IntervalOffset_tf.Value<app.IntervalOnset_tf.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect Offset time!', ...
                        'The specified offset time is smaller than the onset time. Please select a bigger or equal value.'),...
                        'Incorrect offset','error');
                        return 
            end
            
                        
            if strcmp(app.ProcessingChoice.Value, 'Peaks detection')
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Guten Tag!',...
                'Me about to detect the peak (global maxima) in the specified interval and electrodes.',...
                'I will record an file at the specified location',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            elseif strcmp(app.ProcessingChoice.Value, 'Average time interval')
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Get Ready!',...
                'Me about to export the average time in the specified interval for the specified electrodes',...
                'I will record an file at the specified location with all participants''result',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            else
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Get Ready!',...
                'Me about to export the timecourse in the specified interval for the specified electrodes',...
                'I will record an file at the specified location with all participants''result',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            end
            
            if strcmpi(Run,'Cancel')
                return
            end
            
           
            %% PROCESSING INFORMATION SHEET
            
            currentVersionModule = dir([mfilename('fullpath') '.mlapp']); % Information about current file


            %% RUN MODULE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            
            %check the number of specified electrodes
            electrodesSpec=[];
            if ~isempty(app.ChannelsEditField.Value)
                electrodesSpec=str2num(app.ChannelsEditField.Value);
            end
            
            %check if specified all electrodes
            if strcmp(app.ChannelsEditField.Value,'*')
                electrodesSpec=[1:1:ProcessedDataTable.nChan(1)];
            end


            %compute the number of element which will generated/studied
            nbValue=length(electrodesSpec); 
            if app.AverageCheckBox.Value
                nbValue=nbValue+1;
            end
            if app.GFPCheckBox.Value
                nbValue=nbValue+1;
            end
            if app.GMDCheckBox.Value
                nbValue=nbValue+1;
            end
            
            %initivalisation of output 
            if strcmp(app.ProcessingChoice.Value, 'Peaks detection') %for peak detection              
                output=cell(nbFiles,nbValue*2+2); %will contains values
                outputHeader=cell(1,nbValue*2+2); %will contains headers
                check=cell(nbFiles,nbValue+1);
                checkHeader=cell(1,nbValue+1); 
                
                outputHeader{1,1}='Files'; checkHeader{1,1}='Files';
 
                disp('Run peaks detection detection...')
            elseif strcmp(app.ProcessingChoice.Value, 'Average time interval') %for value extraction detection               
                output=cell(nbFiles,nbValue+1); %will contains values
                outputHeader=cell(1,nbValue+1); %will contains headers
                
                outputHeader{1,1}='Files';
                
                disp('Run Average value extraction...')
            else
                nbTF=(app.IntervalOffset_tf.Value+1)-(app.IntervalOnset_tf.Value+1)+1;
                
                if strcmp(app.SelectOutputTime.Value,'2D files')                    
                    output=cell(nbTF,nbValue+1); %will contains values
                    outputHeader=cell(1,nbValue+1); %will contains headers                
                    outputHeader{1,1}='TF';
                    tempsavefileNameOutput=strings([nbFiles,1]);
                else
                    output=zeros(nbTF,nbValue,nbFiles);
                end
            
            end


            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:nbFiles  % Load file by file
                waitbar(file/nbFiles,h, strcat('Processing File ',num2str(file),'/',num2str(nbFiles)));
                
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                elseif strcmpi(FileExtension,'.ris')
                    [header,data] = open_ris(openfilename);
                elseif strcmpi(FileExtension,'.freq')
                    [header,data] = open_freq(openfilename);                    
                else 
                    disp('File extension not recognized.')
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                
                if exist('data','var')                                
                    disp('File loaded!')
                else
                    msgbox(sprintf('%s\n\n%s','Invalid file format!', ...
                    'The processing can not continue with the current file selection. The file format is porbabely not supported.'),...
                    'File format not supported','error');
                    return
                end

                %test if data is 2D or 3D to know how much loop of test is needed (necessary in case of freq analysis)
                    if ndims(data) == 3
                        testLoop=size(data,3);
                    else
                        testLoop=1;
                    end


                %% Run peak detection             
                if strcmp(app.ProcessingChoice.Value, 'Peaks detection')
    
                    output{file,1}=FileName;              
                    output_nbColomn=1;
                    check{file,1}=FileName;
                    
                    %Go through each electrodes
                    for j=1:length(electrodesSpec) %loop over electrodes
                        
                        %detection of peaks for the electrodes
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,data,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,electrodesSpec(j),logical(0));
                        
                        %headers
                        outputHeader{1,output_nbColomn}=strcat('Amp_',header.Channels(j,:)); %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_',header.Channels(j,:)); %header position
                        checkHeader{1,output_nbColomn}=strcat('Check_',header.Channels(j,:)); %header check
                                               
                        %compute of the sum of selected electrodes
                        if j==1
                            sumElectrodes=data(:,electrodesSpec(j));
                        else
                            sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                        end
                        
                    end
                    
                    %Look at the Average
                    if app.AverageCheckBox.Value
                        avgElectrode=sumElectrodes./length(electrodesSpec);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,avgElectrode,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(0));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_Average'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_Average'); %header position
                        checkHeader{1,output_nbColomn}='Check_Average'; %header check
                    end
                    
                    %Look at the GFP
                    if app.GFPCheckBox.Value
                        GFP=compute_gfp(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,GFP,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_GFP'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_GFP'); %header position
                        checkHeader{1,output_nbColomn}='Check_GFP'; %header check
                    end
                    
                    %Look at the GMD
                    if app.GMDCheckBox.Value
                        GMD=compute_gmd(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,GMD,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_GMD'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_GMD'); %header position
                        checkHeader{1,output_nbColomn}='Check_GMD'; %header check
                    end
                    
                    %Save the output file
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        if strcmp(app.SelectOutputTime.Value,'ms')
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.xlsx');
                        else
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\PeakDetection_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.xlsx');                      
                        end
                        writecell([outputHeader;output],fileNameOutput);
                    else
                        if strcmp(app.SelectOutputTime.Value,'ms')
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt');
                        else
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\PeakDetection_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.txt');                      
                        end
                        writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                    end
                    
                    %Save the check file
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        if strcmp(app.CheckfileDropDown.Value,'Yes')
                            if strcmp(app.SelectOutputTime.Value,'ms')
                                fileNameCheck=strcat(app.SaveFolder.Value{1},'\PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.xlsx');
                            else
                                fileNameCheck=strcat(app.SaveFolder.Value{1},'\PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.xlsx');                      
                            end
                            writecell([checkHeader;check],fileNameCheck);
                        end
                    else
                        if strcmp(app.CheckfileDropDown.Value,'Yes')
                            if strcmp(app.SelectOutputTime.Value,'ms')
                                fileNameCheck=strcat(app.SaveFolder.Value{1},'\PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt');
                            else
                                fileNameCheck=strcat(app.SaveFolder.Value{1},'\PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.txt');                      
                            end
                            writecell([checkHeader;check],fileNameCheck,'Delimiter','tab');
                        end
                    end

                %% Average Time interval    
                elseif strcmp(app.ProcessingChoice.Value, 'Average time interval')
                
                    output{file,1}=FileName;              
                    output_nbColomn=1;
                
                    for j=1:length(electrodesSpec) %loop over electrodes
                        
                        %Average value
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)));
                        
                        %headers
                        outputHeader{1,output_nbColomn}=strcat('TimeAvg_',header.Channels(j,:));
                        
                        %compute of the sum of selected electrodes
                        if j==1
                            sumElectrodes=data(:,electrodesSpec(j));
                        else
                            sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                        end
                    end
                    
                    %Look at electrod mean
                    if app.AverageCheckBox.Value
                        avgElectrode=sumElectrodes./length(electrodesSpec);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='TimeAvg_ElectodesAvg'; %header amp                       
                    end
                    
                    %Look at the GFP
                    if app.GFPCheckBox.Value
                        GFP=compute_gfp(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='TimeAvg_GFP'; %header amp                        
                    end
                    
                    %Look at the GMD
                    if app.GMDCheckBox.Value
                        GMD=compute_gmd(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='GMD'; %header amp                        
                    end
                    
                    %Save the output file                    
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        fileNameOutput=strcat(app.SaveFolder.Value{1},'\AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.xlsx');                    
                        writecell([outputHeader;output],fileNameOutput);
                    else
                        fileNameOutput=strcat(app.SaveFolder.Value{1},'\AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt');                    
                        writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                    end

                %% Timecourse extraction
                else
                    output_nbColomn=1;

                    if strcmp(app.SelectOutputTime.Value,'2D files') 
                        %% save 2D files
                        output=cell(nbTF,nbValue+1); %reset output
                        output(:,1)=num2cell([app.IntervalOnset_tf.Value:1:app.IntervalOffset_tf.Value]);

                        for j=1:length(electrodesSpec) %loop over electrodes
                            
                            %Time course extraction per electrodes
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)));
                            
                            %headers
                            outputHeader{1,output_nbColomn}=strcat(header.Channels(j,:));
                            
                            %compute of the sum of selected electrodes
                            if j==1
                                sumElectrodes=data(:,electrodesSpec(j));
                            else
                                sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                            end
                        end
                        
                        %Look at electrod mean
                        if app.AverageCheckBox.Value
                            avgElectrode=sumElectrodes./length(electrodesSpec);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='ElectodesAvg'; %header amp                       
                        end
                        
                        %Look at the GFP
                        if app.GFPCheckBox.Value
                            GFP=compute_gfp(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='GFP'; %header amp                        
                        end
                        
                        %Look at the GMD
                        if app.GMDCheckBox.Value
                            GMD=compute_gmd(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='GMD'; %header amp                        
                        end
                        
                        %Save the output file                    
                        if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\',FileName,'_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.xlsx');                    
                            writecell([outputHeader;output],fileNameOutput);
                        else
                            fileNameOutput=strcat(app.SaveFolder.Value{1},'\',FileName,'_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt');                    
                            writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                        end
                        tempsavefileNameOutput(file)=fileNameOutput;

                    else 
                        %% save 3D mat
                        output_nbColomn=0;
                        for j=1:length(electrodesSpec) %loop over electrodes
                            
                            %Time course extraction per electrodes
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j));
                            
                            %compute of the sum of selected electrodes
                            if j==1
                                sumElectrodes=data(:,electrodesSpec(j));
                            else
                                sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                            end
                        end
                        
                        %Look at electrod mean
                        if app.AverageCheckBox.Value
                            avgElectrode=sumElectrodes./length(electrodesSpec);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                  
                        end
                        
                        %Look at the GFP
                        if app.GFPCheckBox.Value
                            GFP=compute_gfp(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn, file)=GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                       
                        end
                        
                        %Look at the GMD
                        if app.GMDCheckBox.Value
                            GMD=compute_gmd(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                      
                        end                       

                    end


                end    


            end

            %Save the output file in case of timecourse 3D
            if strcmp(app.SelectOutputTime.Value,'3D file')
                fileNameOutput=strcat(app.SaveFolder.Value{1},'\','3D_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.mat');
                save(fileNameOutput, "output");
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                %peak detection
                if strcmp(app.SelectOutputTime.Value,'ms')
                   PifFileID = fopen(strcat(app.SaveFolder.Value{1},'\PIF_PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt'),'w');
                else
                   PifFileID = fopen(strcat(app.SaveFolder.Value{1},'\PIF_PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt'),'w'); 
                end

                fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal PeakDetection Info File',...
                '======================================================================='); % header
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                %Average Time Interval
                 PifFileID = fopen(strcat(app.SaveFolder.Value{1},'\PIF_AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt'),'w');
                 
                 fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Average Time Interval Info File',...
                '======================================================================='); % header
            else
                %Timecourse extraction
                 PifFileID = fopen(strcat(app.SaveFolder.Value{1},'\PIF_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt'),'w');
                 
                 fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Timecourse export Info File',...
                '======================================================================='); % header
            end
           
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});         
            
            % Processing parameters
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','PROCESSING PARAMETERS');
            fprintf(PifFileID,'%s\t%s\r\n','Processing performed:',app.ProcessingChoice.Value);
            fprintf(PifFileID,'%s\t%d\r\n','Sampling Rate (in Hz):', app.SamplingRate.Value);
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval onset:',app.IntervalOnset_ms.Value,' ms,',app.IntervalOnset_tf.Value,' tf');
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval offset:',app.IntervalOffset_ms.Value,' ms,',app.IntervalOffset_tf.Value,' tf');
            fprintf(PifFileID,'%s\t%s\r\n','Electrodes processed indices:',app.ChannelsEditField.Value);
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                %peak detection         
                if strcmp(app.PeakDropDown.Value,'Positive')
                    fprintf(PifFileID,'%s\t%s\r\n','Type of peaks detected:','Positive (maxima in the interval)');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Type of peaks detected:','Negative (minima in the interval)');
                end
                if app.AverageCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','NO');
                end
                if app.GFPCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','GFP studied (look only at maxima):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','GFP studied (look only at maxima):','NO');
                end
                if app.GMDCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Dis/GMD studied (look only at maxima):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Dis/GMD studied (look only at maxima):','NO');
                end
            else
                %AverageTimeInterval and Timecourse extraction
                if app.AverageCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','NO');
                end
                if app.GFPCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','GFP:','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','GFP:','NO');
                end
                if app.GMDCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Dis/GMD:','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Dis/GMD:','NO');
                end
            end
            
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name)');
            for file2 = 1:nbFiles
                temp=fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file2),'..'),app.DataTable.File(file2));
                fprintf(PifFileID,'%s\r\n',temp{1});
            end
            
            %output files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                fprintf(PifFileID,'%s\t%s\r\n','Output file (Peak amplitude and position):',fileNameOutput);
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                fprintf(PifFileID,'%s\t%s\r\n','Output file:',fileNameOutput);
            else %timecourse extraction
                if strcmp(app.SelectOutputTime.Value,'2D files')
                    fprintf(PifFileID,'%s\r\n','Timecourse for each input file was record in 2D files (Time X Electrodes)');
                    for file3 = 1:nbFiles
                        fprintf(PifFileID,'%s\r\n',tempsavefileNameOutput(file3));
                    end

                else %for 3D mat file
                    fprintf(PifFileID,'%s\r\n','Timecourse of all input files was record in 3D files (Time X Electrodes X Input file):');
                    fprintf(PifFileID,'%s\r\n',fileNameOutput);
                end

            end            
            
            if and(strcmp(app.CheckfileDropDown.Value,'Yes'),strcmp(app.ProcessingChoice.Value,'Peaks detection'))
                fprintf(PifFileID,'%s\t%s\r\n','Check file (suggestion of position to visually check the detection when value is different from 0 [1=no peak detected, 2=more than one peak in the interval]):',fileNameCheck);
            end

            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fclose(PifFileID);

            delete (h);
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                rz = msgbox('Peak detection done.');
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                rz = msgbox('Average Time Interval done.');
            else
                rz = msgbox('Timecourse export done.');
            end
            
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_Statistics
        function EEGpal_StatisticsCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: IntervalOnset_ms
        function IntervalOnset_msValueChanged(app, event)
            app.IntervalOnset_tf.Value     = ms2tf(app,app.IntervalOnset_ms.Value,app.SamplingRate.Value);
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: IntervalOnset_tf
        function IntervalOnset_tfValueChanged(app, event)
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: IntervalOffset_ms
        function IntervalOffset_msValueChanged(app, event)
            app.IntervalOffset_tf.Value    = ms2tf(app,app.IntervalOffset_ms.Value,app.SamplingRate.Value);
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpal_Statistics.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {507, 507, 507};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {507, 507};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: IntervalOffset_tf
        function IntervalOffset_tfValueChanged(app, event)
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value);            
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.IntervalOffset_tf.Enable   = 'off';
                app.IntervalOffset_ms.Enable   = 'off';
                try
                    app.IntervalOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.IntervalOffset_tf.Value    = 0;
                    app.IntervalOffset_ms.Value    = 0;
                end
            else
                app.IntervalOffset_tf.Enable       = 'on';
                app.IntervalOffset_ms.Enable       = 'on';
            end        
        end

        % Value changed function: ChannelsEditField
        function ChannelsEditFieldValueChanged(app, event)
            if ~isempty(app.ChannelsEditField.Value)
                app.AverageCheckBox.Enable  = 'on';
            else
                app.AverageCheckBox.Enable  = 'off';
                app.AverageCheckBox.Value  = 0;
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_PeaksTraceExport_module.pdf');
            
            if ispc
                % For Windows
                winopen(filePath);
            elseif ismac
                % For macOS
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                disp('This command is not supported by your OS');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_Statistics = uifigure('Visible', 'off');
            app.EEGpal_Statistics.Name = 'MATLAB App';
            app.EEGpal_Statistics.CloseRequestFcn = createCallbackFcn(app, @EEGpal_StatisticsCloseRequest, true);
            app.EEGpal_Statistics.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpal_Statistics);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StatisticsmoduleLabel = uilabel(app.LeftPanel);
            app.StatisticsmoduleLabel.Text = 'Statistics module';
            app.InstructModule = uilabel(app.LeftPanel);
            app.InstructModule.Text = 'This module to performs statistics over trace (amplitude), frequency or inverse solution ';
            app.Epoch_RelativeInterval1 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1.Text = 'Relative to file start';
            app.Epoch_RelativeInterval1_2 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1_2.Text = 'Time interval';
            app.msLabel = uilabel(app.LeftPanel);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.LeftPanel);
            app.tfLabel.Text = '[tf]';
            app.IntervalOnset_tf = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOnset_tf.Limits = [0 Inf];
            app.IntervalOnset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOnset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_tfValueChanged, true);
            app.IntervalOnset_tf.Tooltip = {''};
            app.IntervalOffset_tf = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOffset_tf.Limits = [0 Inf];
            app.IntervalOffset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOffset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_tfValueChanged, true);
            app.IntervalOffset_tf.Tooltip = {''};
            app.EndOfFile = uibutton(app.LeftPanel, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'End of File'; 'When selected, ''Epoch offset'', ''Epoch duration'', and ''Number of sub-epochs'' values based on the first file of the list in the main window.,When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'EoF';
            app.Epoch_RelativeInterval1_3 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1_3.Text = 'Channels to process';
            app.AverageCheckBox = uicheckbox(app.LeftPanel);
            app.AverageCheckBox.Text = 'Average selected channel(s)';
            app.GFPCheckBox = uicheckbox(app.LeftPanel);
            app.GFPCheckBox.Text = 'GFP';
            app.GMDCheckBox = uicheckbox(app.LeftPanel);
            app.GMDCheckBox.Text = 'Dis/GMD';
            app.Instruct_timeinter1 = uilabel(app.LeftPanel);
            app.Instruct_timeinter1.Text = 'Time must be specified in the Cartool scale (first time fame is tf=0).';
            app.IntervalonsetLabel = uilabel(app.LeftPanel);
            app.IntervalonsetLabel.Text = 'Interval onset:';
            app.IntervalOnset_ms = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOnset_ms.Limits = [0 Inf];
            app.IntervalOnset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOnset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_msValueChanged, true);
            app.IntervalOnset_ms.Tooltip = {''};
            app.IntervaloffsetLabel = uilabel(app.LeftPanel);
            app.IntervaloffsetLabel.Text = 'Interval offset:';
            app.IntervalOffset_ms = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOffset_ms.Limits = [0 Inf];
            app.IntervalOffset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOffset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_msValueChanged, true);
            app.IntervalOffset_ms.Tooltip = {''};
            app.ChannelsEditFieldLabel = uilabel(app.LeftPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.LeftPanel, 'text');
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsEditFieldValueChanged, true);
            app.ChannelsEditField.Value = '*';
            app.SamplingRateinHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateinHzEditFieldLabel.Text = 'Sampling Rate (in Hz)';
            app.SamplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.InstructElements1 = uilabel(app.LeftPanel);
            app.InstructElements1.Text = 'Specify the channel number separate by space (e.g 1 2 64). Put * to select all channels ';
            app.AveragetimeCheckBox = uicheckbox(app.LeftPanel);
            app.AveragetimeCheckBox.Text = 'Average time';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Epoch_RelativeInterval1_4 = uilabel(app.CenterPanel);
            app.Epoch_RelativeInterval1_4.Text = 'Design';
            app.designDescription = uilabel(app.CenterPanel);
            app.designDescription.Text = 'Description of the Design';
            app.RightPanel = uipanel(app.GridLayout);
            app.OUTPUTOPTIONSLabel = uilabel(app.RightPanel);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.InstructionFolderLabel = uilabel(app.RightPanel);
            app.InstructionFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.RightPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.APPLYLabel = uilabel(app.RightPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.RightPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.SavingfolderLabel = uilabel(app.RightPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.RightPanel);
            app.SaveFolder.Editable = 'off';
            app.OptionalsuffixinoutputfilenameEditFieldLabel = uilabel(app.RightPanel);
            app.OptionalsuffixinoutputfilenameEditFieldLabel.Text = 'Optional suffix in output file name';
            app.OptionalSuffix = uieditfield(app.RightPanel, 'text');
            app.OutputformatDropDownLabel = uilabel(app.RightPanel);
            app.OutputformatDropDownLabel.Text = 'Output format';
            app.OutputformatDropDown = uidropdown(app.RightPanel);
            app.OutputformatDropDown.Items = {'.ep', '.eph', '.sef', '.set', '.ris', '.csv'};
            app.OutputformatDropDown.Value = '.ep';
            % Show the figure after all components are created
            app.EEGpal_Statistics.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Statistics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_Statistics)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_Statistics)
        end
    end
end