classdef EEGpalCS_mod_BatchProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_Batch            matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SavingOutputOption      matlab.ui.control.DropDown
        WheredoyouwanttosavetheouputfilesLabel  matlab.ui.control.Label
        OUTPUTOPTIONLabel       matlab.ui.control.Label
        CancelButton            matlab.ui.control.Button
        SaveinmemoryButton      matlab.ui.control.Button
        APPLYLabel              matlab.ui.control.Label
        ModulesAvailabelLabel   matlab.ui.control.Label
        ProcessesparameterssavedinthisfileLabel  matlab.ui.control.Label
        HelpButton              matlab.ui.control.Button
        ProcessingAnalysisparametersfileEditField  matlab.ui.control.EditField
        ProcessingAnalysisparametersfileEditFieldLabel  matlab.ui.control.Label
        SaveFolder              matlab.ui.control.TextArea
        SavingfolderLabel       matlab.ui.control.Label
        SelectFolderButton      matlab.ui.control.Button
        SelectUpperFolderLabel  matlab.ui.control.Label
        BATCHProcessingLabel    matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        ResetButton             matlab.ui.control.Button
        Option9DropDown         matlab.ui.control.DropDown
        Option8DropDown         matlab.ui.control.DropDown
        Option7DropDown         matlab.ui.control.DropDown
        Option6DropDown         matlab.ui.control.DropDown
        Option5DropDown         matlab.ui.control.DropDown
        Option4DropDown         matlab.ui.control.DropDown
        Option3DropDown         matlab.ui.control.DropDown
        Option2DropDown         matlab.ui.control.DropDown
        Option1DropDown         matlab.ui.control.DropDown
        Process9DropDown        matlab.ui.control.DropDown
        Label_10                matlab.ui.control.Label
        Process8DropDown        matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        Process7DropDown        matlab.ui.control.DropDown
        Label_8                 matlab.ui.control.Label
        Process6DropDown        matlab.ui.control.DropDown
        Label_7                 matlab.ui.control.Label
        Process5DropDown        matlab.ui.control.DropDown
        Label_6                 matlab.ui.control.Label
        Process4DropDown        matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        Process3DropDown        matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        Process2DropDown        matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        Process1DropDown        matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        OptionLabel             matlab.ui.control.Label
        SucessionLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
        ModulesAvailable % Module available for processing
        ModulesAvailableStartup % Module available save
        ProcessDropDownList  % Will contain the option of all Process DropDown List
        ProcessDropDownListLabel  % Will contain the label of all Process DropDown List
        OptionsDropDownList  % Will contain the option of all Options DropDown List
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)  
            %restaure the processing option
            processList = app.SessionParameters.BatchProcessing.Processes;
            n = size(processList,1);
           
            for i=1:n
                currentDropDownList = app.ProcessDropDownList{i};
                currentOptionDropDownList = app.OptionsDropDownList{i}; %retrive option
                
                %process
                currentDropDownList.Value               = processList (i,1);
                applyProcessChoice(app,i,currentDropDownList.Value);
                lookForNextAvalaibleProcess(app,i+1);

                %option
                if ~isempty(processList{i,2})
                    currentOptionDropDownList.Value     = processList (i,2);
                end

                %look if processing already done
                if processList {i,3}==1
                    currentDropDownList.Enable          = "off";
                    currentOptionDropDownList.Enable    = "off";
                end
            
            end

            
            %Saving options
            app.SavingOutputOption.Value            = app.SessionParameters.BatchProcessing.WhereToSave;
            SavingOutputOptionValueChanged(app);
            app.SaveFolder.Value                    = app.SessionParameters.BatchProcessing.SaveFolder;

            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            
            % Create the batch processing matrix
            n=0;
            processList=cell(n,3); %colomn1 : Process, column2 : option of the process, column3 (0 = not processed, 1 = already processed, 2=error)
            for i=1:9 %reset layout
                currentDropDownList = app.ProcessDropDownList{i}; %retrive the current DownDown to edit
                currentOptionDropDownList = app.OptionsDropDownList{i}; %retrive option

                if ~isempty(currentDropDownList.Value) %if there is a parameter and is not already executed 
                    n=n+1;
                    processList{n,1}=currentDropDownList.Value;                    
                    processList{n,2}=currentOptionDropDownList.Value;                    
                    if strcmp(currentDropDownList.Enable,'off')
                        processList{n,3}=1; %already processed
                    else
                        processList{n,3}=0; %need to be processed
                    end

                end
            end            
            SessionParameters.BatchProcessing.Processes                     = processList;

            
            % SAVE OPTION
            SessionParameters.BatchProcessing.WhereToSave                   = app.SavingOutputOption.Value; 
            SessionParameters.BatchProcessing.SaveFolder                    = app.SaveFolder.Value;
            
        end
        
        function lookForNextAvalaibleProcess(app,numProcess)
            
            
            %reset all the ProcessDropDown and OptionDropDown which are below (to avoid bugs)
            if numProcess ~= 9
                for i=numProcess:9
                    currentDropDownList = app.ProcessDropDownList{i}; %retrive the current DownDown to edit
                    currentDropDownList.Visible = "off";
                    currentDropDownList.Enable = "off";
                    switch currentDropDownList.Value
                        case 'Bridge Detection'
                            app.ModulesAvailable(1)=1;
                        case 'Filtering'
                            app.ModulesAvailable(2)=1;
                        case 'ICA'
                            app.ModulesAvailable(3)=1;                          
                        case 'Interpolation'
                            app.ModulesAvailable(4)=1;                           
                        case 'Rereference_Reref1'
                            app.ModulesAvailable(5)=1;
                        case 'Rereference_Reref2'
                            app.ModulesAvailable(6)=1;
                        case 'Epoching'
                            app.ModulesAvailable(7)=1;
                        case 'GEDAI'
                            app.ModulesAvailable(8)=1;

                    end
                    currentDropDownList.Items={''};
                    currentDropDownList = app.ProcessDropDownListLabel{i}; %retrive the Label
                    currentDropDownList.Visible = "off";
                    currentDropDownList.Enable = "off";
                    currentDropDownList = app.OptionsDropDownList{i}; %retrive option
                    currentDropDownList.Visible = "off";
                end
            end


            %Determine the next possible processings
            currentDropDownList = app.ProcessDropDownList{numProcess}; %retrive the current DownDown to edit            
            currentDropDownListLabel = app.ProcessDropDownListLabel{numProcess};
            availableProcess{1}='';
            indice=1;

            for k=1:size(app.ModulesAvailable,1)              
                if app.ModulesAvailable(k) == 1
                    indice=indice+1;
                    switch k
                        case 1
                            availableProcess{indice}='Bridge Detection';
                        case 2
                            availableProcess{indice}='Filtering';
                        case 3
                            availableProcess{indice}='ICA';                            
                        case 4
                            availableProcess{indice}='Interpolation';
                        case 5
                            availableProcess{indice}='Rereference_Reref1';
                        case 6
                            availableProcess{indice}='Rereference_Reref2';
                        case 7
                            availableProcess{indice}='Epoching';
                        case 8
                            availableProcess{indice}='GEDAI';
                    end
                end
            end            

            % set the next available processings
            currentDropDownList.Items = availableProcess;
            currentDropDownList.Value = ''; %Reset

            %make it visible
            currentDropDownList.Visible = "on";
            currentDropDownList.Enable = "on";
            currentDropDownListLabel.Visible = "on";
            currentDropDownListLabel.Enable = "on";
            currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
            currentOptionsDropDownList.Visible="off";          
            
        end
        
        function applyProcessChoice(app, numProcess, Process)
            switch Process
                case 'Bridge Detection'
                    app.ModulesAvailable(1)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'Filtering'
                    app.ModulesAvailable(2)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'ICA'
                    app.ModulesAvailable(3)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Items = {'Run only the ICA decomposition','Automatic Bad Components Rejection'};
                    currentOptionsDropDownList.Value = 'Run only the ICA decomposition';
                    currentOptionsDropDownList.Visible = "on";
                case 'Interpolation'
                    app.ModulesAvailable(4)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};                    
                    currentOptionsDropDownList.Items = {'Manual Bad Electrodes Inspection','Automatic Bad Electrodes Rejection'};                   
                    currentOptionsDropDownList.Visible = "on";
                case 'Rereference_Reref1'
                    app.ModulesAvailable(5)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'Rereference_Reref2'
                    app.ModulesAvailable(6)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'Epoching'
                    app.ModulesAvailable(7)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'GEDAI'
                    app.ModulesAvailable(8)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
            end

        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            ParameterFilePath               = app.EEGpalCS_Main.SessionParametersFilePath.Value;
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;     
           
            %create of the Layout of the app to enter the parameters
            app.ProcessingAnalysisparametersfileEditField.Value = ParameterFilePath;
            
            % Determine the available modules for batch
            app.ModulesAvailable=zeros(8,1); % Initialisation
            TextModules="";

            app.ModulesAvailable(1)=1; %eBridge
            TextModules="Bridge Detection";
            if isfield (app.SessionParameters,'Filtering')
                app.ModulesAvailable(2)=1; %Filtering+
                TextModules=[TextModules,"Filtering+"];
            end
            if isfield (app.SessionParameters,'ICA')
                app.ModulesAvailable(3)=1; %ICA
                TextModules=[TextModules,"ICA"];
            end
            if isfield (app.SessionParameters,'Interpolation')
                app.ModulesAvailable(4)=1; %Interpolation
                TextModules=[TextModules,"Interpolation"];
            end
            if isfield (app.SessionParameters,'Rereference')
                app.ModulesAvailable(5)=1; %Rereference
                TextModules=[TextModules,"Rereference_Reref1"];
                app.ModulesAvailable(6)=1; %Rereference
                TextModules=[TextModules,"Rereference_Reref2"];
            end
            if isfield (app.SessionParameters,'Epoching')
                app.ModulesAvailable(7)=1; %Epoching
                TextModules=[TextModules,"Epoching"];
            end
            if isfield (app.SessionParameters,'GEDAI')
                app.ModulesAvailable(8)=1; %Epoching
                TextModules=[TextModules,"GEDAI"];
            end
            
            app.ModulesAvailabelLabel.Text=TextModules; % Display the available modules in the left column
            app.ModulesAvailableStartup=app.ModulesAvailable;

            % Create a superclass to hold all the Process dropdown 
            app.ProcessDropDownList = {
                app.Process1DropDown;
                app.Process2DropDown;
                app.Process3DropDown;
                app.Process4DropDown;
                app.Process5DropDown;
                app.Process6DropDown;
                app.Process7DropDown;
                app.Process8DropDown;
                app.Process9DropDown
                };

            % Create a superclass to hold all the Process dropdown Label
            app.ProcessDropDownListLabel = {
                app.Label_2;
                app.Label_3;
                app.Label_4;
                app.Label_5;
                app.Label_6;
                app.Label_7;
                app.Label_8;
                app.Label_9;
                app.Label_10
                };

            % Create a superclass to hold all the Option dropdown 
            app.OptionsDropDownList = {
                app.Option1DropDown;
                app.Option2DropDown;
                app.Option3DropDown;
                app.Option4DropDown;
                app.Option5DropDown;
                app.Option6DropDown;
                app.Option7DropDown;
                app.Option8DropDown;
                app.Option9DropDown
                };
                     
            % put the intialisation of the first here
            lookForNextAvalaibleProcess(app,1);


            % Apply Session parameters 
            try
                applySessionParam(app)
            catch
            end


        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_Batch); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            %cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            
            if strcmp(app.SavingOutputOption.Value,'In the following folder') & isempty(app.SaveFolder.Value{1})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';

            % Update parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;

            % Allow the Batch execution
            app.EEGpalCS_Main.RunBatchButton.Enable = 'on';

            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_Batch
        function EEGpal_BatchCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_BATCHparameters_tool.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_BATCHparameters_tool.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_BATCHparameters_tool.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: Process1DropDown
        function Process1DropDownValueChanged(app, event)
            value = app.Process1DropDown.Value;

            applyProcessChoice(app,1,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,2);


        end

        % Value changed function: Process2DropDown
        function Process2DropDownValueChanged(app, event)
            value = app.Process2DropDown.Value;
            
            applyProcessChoice(app,2,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,3);
        end

        % Value changed function: Process3DropDown
        function Process3DropDownValueChanged(app, event)
            value = app.Process3DropDown.Value;            
            
            applyProcessChoice(app,3,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,4);
        end

        % Value changed function: Process4DropDown
        function Process4DropDownValueChanged(app, event)
            value = app.Process4DropDown.Value;
            
            applyProcessChoice(app,4,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,5);
        end

        % Value changed function: Process5DropDown
        function Process5DropDownValueChanged(app, event)
            value = app.Process5DropDown.Value;
            
            applyProcessChoice(app,5,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,6);
        end

        % Value changed function: Process6DropDown
        function Process6DropDownValueChanged(app, event)
            value = app.Process6DropDown.Value;
            
            applyProcessChoice(app,6,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,7);
        end

        % Value changed function: Process7DropDown
        function Process7DropDownValueChanged(app, event)
            value = app.Process7DropDown.Value;
            
            applyProcessChoice(app,7,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,8);
        end

        % Value changed function: Process8DropDown
        function Process8DropDownValueChanged(app, event)
            value = app.Process8DropDown.Value;
            
            applyProcessChoice(app,8,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,9);
        end

        % Value changed function: Process9DropDown
        function Process9DropDownValueChanged(app, event)
            value = app.Process9DropDown.Value;
            
            applyProcessChoice(app,9,value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ModulesAvailable=app.ModulesAvailableStartup;
           
            for i=1:9 %reset layout
                currentDropDownList = app.ProcessDropDownList{i}; %retrive the current DownDown to edit
                currentDropDownList.Visible = "off";
                currentDropDownList.Enable = "on";                
                currentDropDownList.Items={''};
                currentDropDownList = app.ProcessDropDownListLabel{i}; %retrive the Label
                currentDropDownList.Visible = "off";
                currentDropDownList.Enable = "on";
                currentDropDownList = app.OptionsDropDownList{i}; %retrive option
                currentDropDownList.Visible = "off";
                currentDropDownList.Enable = "on";
                currentDropDownList.Items={''};
            end
            
            % intialisation of the first 
            lookForNextAvalaibleProcess(app,1);
            
        end

        % Value changed function: SavingOutputOption
        function SavingOutputOptionValueChanged(app, event)
            if strcmp(app.SavingOutputOption.Value, 'In the folders specified inside of each module')
                app.SelectFolderButton.Enable = "off";
                app.SelectUpperFolderLabel.Enable = "off";
                app.SaveFolder.Enable = "off";
            else
                app.SelectFolderButton.Enable = "on";
                app.SelectUpperFolderLabel.Enable = "on";
                app.SaveFolder.Enable = "on";
            end   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpal_Batch.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {646, 646};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_Batch = uifigure('Visible', 'off');
            app.EEGpal_Batch.Name = 'EEGpal_BatchProcessing';
            app.EEGpal_Batch.CloseRequestFcn = createCallbackFcn(app, @EEGpal_BatchCloseRequest, true);
            app.EEGpal_Batch.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpal_Batch);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BATCHProcessingLabel = uilabel(app.LeftPanel);
            app.BATCHProcessingLabel.Text = 'BATCH Processing';
            app.SelectUpperFolderLabel = uilabel(app.LeftPanel);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.LeftPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.LeftPanel);
            app.SaveFolder.Editable = 'off';
            app.ProcessingAnalysisparametersfileEditFieldLabel = uilabel(app.LeftPanel);
            app.ProcessingAnalysisparametersfileEditFieldLabel.Text = 'Processing/Analysis parameters file';
            app.ProcessingAnalysisparametersfileEditField = uieditfield(app.LeftPanel, 'text');
            app.ProcessingAnalysisparametersfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.ProcessesparameterssavedinthisfileLabel = uilabel(app.LeftPanel);
            app.ProcessesparameterssavedinthisfileLabel.Text = 'Process(es) parameters saved in this file';
            app.ModulesAvailabelLabel = uilabel(app.LeftPanel);
            app.APPLYLabel = uilabel(app.LeftPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.SaveinmemoryButton = uibutton(app.LeftPanel, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.OUTPUTOPTIONLabel = uilabel(app.LeftPanel);
            app.OUTPUTOPTIONLabel.Text = 'OUTPUT OPTION';
            app.WheredoyouwanttosavetheouputfilesLabel = uilabel(app.LeftPanel);
            app.WheredoyouwanttosavetheouputfilesLabel.Text = 'Where do you want to save the ouput files ?';
            app.SavingOutputOption = uidropdown(app.LeftPanel);
            app.SavingOutputOption.Items = {'In the following folder', 'In the folders specified inside of each module'};
            app.SavingOutputOption.ValueChangedFcn = createCallbackFcn(app, @SavingOutputOptionValueChanged, true);
            app.SavingOutputOption.Value = 'In the following folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.SucessionLabel = uilabel(app.RightPanel);
            app.SucessionLabel.Text = 'Sucession of process(es)';
            app.OptionLabel = uilabel(app.RightPanel);
            app.OptionLabel.Text = 'Options';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '1';
            app.Process1DropDown = uidropdown(app.RightPanel);
            app.Process1DropDown.ValueChangedFcn = createCallbackFcn(app, @Process1DropDownValueChanged, true);
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '2';
            app.Process2DropDown = uidropdown(app.RightPanel);
            app.Process2DropDown.ValueChangedFcn = createCallbackFcn(app, @Process2DropDownValueChanged, true);
            app.Process2DropDown.Visible = 'off';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '3';
            app.Process3DropDown = uidropdown(app.RightPanel);
            app.Process3DropDown.ValueChangedFcn = createCallbackFcn(app, @Process3DropDownValueChanged, true);
            app.Process3DropDown.Visible = 'off';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '4';
            app.Process4DropDown = uidropdown(app.RightPanel);
            app.Process4DropDown.ValueChangedFcn = createCallbackFcn(app, @Process4DropDownValueChanged, true);
            app.Process4DropDown.Visible = 'off';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '5';
            app.Process5DropDown = uidropdown(app.RightPanel);
            app.Process5DropDown.ValueChangedFcn = createCallbackFcn(app, @Process5DropDownValueChanged, true);
            app.Process5DropDown.Visible = 'off';
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '6';
            app.Process6DropDown = uidropdown(app.RightPanel);
            app.Process6DropDown.ValueChangedFcn = createCallbackFcn(app, @Process6DropDownValueChanged, true);
            app.Process6DropDown.Visible = 'off';
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = '7';
            app.Process7DropDown = uidropdown(app.RightPanel);
            app.Process7DropDown.ValueChangedFcn = createCallbackFcn(app, @Process7DropDownValueChanged, true);
            app.Process7DropDown.Visible = 'off';
            app.Label_9 = uilabel(app.RightPanel);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = '8';
            app.Process8DropDown = uidropdown(app.RightPanel);
            app.Process8DropDown.ValueChangedFcn = createCallbackFcn(app, @Process8DropDownValueChanged, true);
            app.Process8DropDown.Visible = 'off';
            app.Label_10 = uilabel(app.RightPanel);
            app.Label_10.Visible = 'off';
            app.Label_10.Text = '9';
            app.Process9DropDown = uidropdown(app.RightPanel);
            app.Process9DropDown.ValueChangedFcn = createCallbackFcn(app, @Process9DropDownValueChanged, true);
            app.Process9DropDown.Visible = 'off';
            app.Option1DropDown = uidropdown(app.RightPanel);
            app.Option1DropDown.Items = {};
            app.Option1DropDown.Visible = 'off';
            app.Option1DropDown.Value = {};
            app.Option2DropDown = uidropdown(app.RightPanel);
            app.Option2DropDown.Items = {};
            app.Option2DropDown.Visible = 'off';
            app.Option2DropDown.Value = {};
            app.Option3DropDown = uidropdown(app.RightPanel);
            app.Option3DropDown.Items = {};
            app.Option3DropDown.Visible = 'off';
            app.Option3DropDown.Value = {};
            app.Option4DropDown = uidropdown(app.RightPanel);
            app.Option4DropDown.Items = {};
            app.Option4DropDown.Visible = 'off';
            app.Option4DropDown.Value = {};
            app.Option5DropDown = uidropdown(app.RightPanel);
            app.Option5DropDown.Items = {};
            app.Option5DropDown.Visible = 'off';
            app.Option5DropDown.Value = {};
            app.Option6DropDown = uidropdown(app.RightPanel);
            app.Option6DropDown.Items = {};
            app.Option6DropDown.Visible = 'off';
            app.Option6DropDown.Value = {};
            app.Option7DropDown = uidropdown(app.RightPanel);
            app.Option7DropDown.Items = {};
            app.Option7DropDown.Visible = 'off';
            app.Option7DropDown.Value = {};
            app.Option8DropDown = uidropdown(app.RightPanel);
            app.Option8DropDown.Items = {};
            app.Option8DropDown.Visible = 'off';
            app.Option8DropDown.Value = {};
            app.Option9DropDown = uidropdown(app.RightPanel);
            app.Option9DropDown.Items = {};
            app.Option9DropDown.Visible = 'off';
            app.Option9DropDown.Value = {};
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.EEGpal_Batch.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_BatchProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_Batch)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_Batch)
        end
    end
end