classdef EEGpalCS_mod_DesignSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Study_Design                 matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        FactTable                    matlab.ui.control.Table
        CondFactTable                matlab.ui.control.Table
        CondInstruction              matlab.ui.control.Label
        NumFact                      matlab.ui.control.NumericEditField
        NumberoffactorsLabel         matlab.ui.control.Label
        NumCond                      matlab.ui.control.NumericEditField
        NumberofconditionsEditFieldLabel  matlab.ui.control.Label
        FirstSubjCode                matlab.ui.control.EditField
        CodeofFIRSTparticipantLabel  matlab.ui.control.Label
        StudyName                    matlab.ui.control.EditField
        StudyNameEditFieldLabel      matlab.ui.control.Label
        STUDYDESIGNLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object        
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            % Design
            app.StudyName.Value             = app.SessionParameters.Design.StudyName;
            app.FirstSubjCode.Value         = app.SessionParameters.Design.FirstSubjCode;
            app.NumCond.Value               = app.SessionParameters.Design.NumCond;
            app.NumFact.Value               = app.SessionParameters.Design.NumFact;
            app.CondFactTable.Data          = app.SessionParameters.Design.CondFactTable;
            app.FactTable.Data              = app.SessionParameters.Design.FactTable;            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % Design
            SessionParameters.Design.StudyName              = app.StudyName.Value;
            SessionParameters.Design.FirstSubjCode          = app.FirstSubjCode.Value;
            SessionParameters.Design.NumCond                = app.NumCond.Value;
            SessionParameters.Design.NumFact                = app.NumFact.Value;
            SessionParameters.Design.CondFactTable          = app.CondFactTable.Data;
            SessionParameters.Design.FactTable              = app.FactTable.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
             %% Update UI with input values                      
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            
            % Saved study parameters
            try
                applyStudyParam(app)
            catch
            end
            
            % Session parameters override saved parameters
%             app.StudyName.Value             = app.SessionParameters.Design.StudyName; % from Main Data Window
%             app.FirstSubjCode.Value         = app.SessionParameters.Design.FirstSubjCode; % from Main Data Window
            try
                applySessionParam(app)
            catch
            end
            
            if ~isempty(app.StudyName.Value) %to be sure to generate the app.CondFactTable.ColumnName at restart
                event.Indices=[1,2];
                FactTableCellEdit(app,event);
            end


        end

        % Value changed function: NumCond
        function NumCondValueChanged(app, event)
            nCond = app.NumCond.Value;
            nKnown = size(app.CondFactTable.Data,1); % Number of conditions already defined
            
            % Check if addition or deletion of conditions
            if nCond < nKnown % Conditions to delete
                app.CondFactTable.Data(nCond+1:nKnown,:) = [];
            else % Conditions to add
                % Default condition names
                COND = strings(nCond-nKnown,1); % empty string (nr rows = nr of conditions)
                for cond = nKnown+1:nCond
                    COND(cond-nKnown,1) = strcat('Condition',{' '},num2str(cond)); % Default condition names
                end
                
                % Combine with factor names
                if app.NumFact.Value == 0 % No factors defined yet
%                     FACT = {'Factor 1'}; % Default factor names
%                     CONDxFACT = strings(nCond-nKnown,length(FACT)+1);
%                     CONDxFACT(:,1) = COND;
                    CONDxFACT = COND;
                else % Factors already definded
                    FACT = cellstr(app.FactTable.Data.(1));
                    CONDxFACT = strings(length(COND),size(app.FactTable.Data,1)+1);
                    CONDxFACT(:,1) = COND;
                    app.CondFactTable.ColumnName = [{'Conditions'}; FACT];
                end
                app.CondFactTable.Data = [app.CondFactTable.Data; CONDxFACT];
%                 app.CondFactTable.ColumnName = [{'Conditions'}; FACT];
            end
        end

        % Value changed function: NumFact
        function NumFactValueChanged(app, event)
            nFact = app.NumFact.Value;
            nKnown = size(app.FactTable.Data,1); % Number of factors already defined
            
            % Check if addition or deletion of factors
            if nFact < nKnown % Factors to delete
                app.CondFactTable.Data(:,1+nFact+1:size(app.CondFactTable.Data,2)) = [];
                app.FactTable.Data(nFact+1:nKnown,:) = [];
            else % Factors to add
                % Default factor names for additional factors
                FACT = strings(nFact-nKnown,1); % empty string (nr rows = nr of conditions)
                for fact = nKnown+1:nFact
                    %FACT(fact-nKnown,1) = strcat('Factor',{' '},num2str(fact)); % Default factor names
                    FACT(fact-nKnown,1) = strcat('Factor',num2str(fact)); % Default factor names
                end
                FACTTABLE = array2table(FACT,'VariableNames',{'Factors'});
                FACTTABLE.Between = repmat({false},height(FACTTABLE),1);
                app.FactTable.Data = [app.FactTable.Data; FACTTABLE];
                
                % Combine with condition names
                if app.NumCond.Value == 0
                    COND = {'Condition 1'}; % Default factor names
                    CONDxFACT = strings(length(COND),nFact+1);
                    CONDxFACT(:,1) = COND;
                else % Conditions already definded
                    CONDxFACT = strings(size(app.CondFactTable.Data,1),nFact-nKnown);
                end
                app.CondFactTable.Data = [app.CondFactTable.Data, CONDxFACT];
                app.CondFactTable.ColumnName = [{'Conditions'}; cellstr(strcat('level_',app.FactTable.Data.(1)))];
            end
        end

        % Cell edit callback: FactTable
        function FactTableCellEdit(app, event)
            NumCondValueChanged(app, event); %be sure that the matrice has correct header dimension
            app.CondFactTable.ColumnName(2:end) = cellstr(strcat('level_', app.FactTable.Data{:,1}));

            %check special character
            if event.Indices(1,2) == 1
                specialCharPattern = '[^a-zA-Z0-9]';
                if ~isempty(regexp(event.NewData, specialCharPattern, 'once')) % special caracter
                    warndlg('Please don''t use special character like space or ''!@#'' in the factor name.','Invalid factor names');
                    app.FactTable.Data.Factors(event.Indices(1,1))=event.PreviousData;
                end
                if ~isempty(str2num(event.NewData{1}(1)))
                    warndlg('Please don''t start your factor name by a number','Invalid factor names');
                    app.FactTable.Data.Factors(event.Indices(1,1))=event.PreviousData;
                end
            end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Check if the CondTable are correctely filled
            % check if any Design name specified
            if isempty(app.StudyName.Value)
                errordlg('Please, specify a design name.','Unspecified design name');
                return;
            end
            
            % check if the number of condition is not 0
            if app.NumCond.Value==0
                errordlg('The number of conditions is still 0. You will need to specify the number of experimental conditions in your design.','Unspecified number of condition');
                return;
            end

            % check if the number of factor is not 0
            if app.NumFact.Value==0
                errordlg('The number of factor(s) is still 0. You will need to specify the number of experimental factors in your design.','Unspecified number of factor');
                return;
            end

            % check if the factor=1 and Condition = 1, then it is not a between 
            if app.NumCond.Value==1 & app.NumFact.Value==1 & app.FactTable.Data.Between{1}
                errordlg('As you have a single factor and level, the factor is automatically a within factor.','Change between for a within factor');
                app.FactTable.Data.Between{1}=false;
                return;
            end

            
            % check if there is any empty value in the CondFact Table
            emptyIndicies = cellfun(@(x) isempty(strtrim(x)), app.CondFactTable.Data(:,2:end));
            if any(emptyIndicies(:))
                errordlg('There is empty cell in the Conditions table. Please specify one level for each factor and conditions','Empty level in condition table');
                return;
            end
            
            %generate levels
            levels="";
            for i=1:app.NumCond.Value
                s="";
                for j=2:app.NumFact.Value+1
                    s=strcat(s,app.CondFactTable.Data(i,j));
                end
                levels(i)=s;
            end
            
            %check if there is any repetition in levels specified across conditions
            [uniqueStrings, ~, indices] = unique(levels, 'stable');
            countOccurrences = accumarray(indices, 1);
            doublons = uniqueStrings(countOccurrences > 1);
            if ~isempty(doublons)
                errordlg('There is a problem in specifying levels. There are two or more conditions with the same combination of levels between factors. You need to complete the condition table to have a unique combination of factor levels per condition.','Incorrect fill of condition table');
                return;
            end

            %check if level value entered in the Condition table are consistent with the number of conditions
            NumCondNumeric = str2double(app.CondFactTable.Data(:,2:end));
            maxValues=0;
            for j=1:app.NumFact.Value
                maxValues(j)=max(NumCondNumeric(:,j));
            end
            if prod(maxValues)~=app.NumCond.Value
                errordlg(['The level values are not coherent with the number of conditions. Fow now, the multiplication level max of each factor is ',num2str(prod(maxValues)),' and the number of specified conditions is ',num2str(app.NumCond.Value),'. These two values should be equal to have a coherent design.'],'Incoherent levels specified');
                return;
            end      
       
            
            %% update main EEGpal windows
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.DesignButton.Enable = 'on';
            
            % Design parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            %update study name
            app.EEGpalCS_Main.StudyName.Value=app.StudyName.Value;

            % update Subject if entered
            if ~isempty(app.FirstSubjCode.Value)
                app.EEGpalCS_Main.FirstSubjCode.Value = app.FirstSubjCode.Value;
                %the update of the list will be carry one by the EEGpalCS_Main module
            end
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.DesignButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: Study_Design
        function Study_DesignCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.DesignButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: CondFactTable
        function CondFactTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if indices(2)>1 %don't test the first column
                if ~all(isstrprop(newData, 'digit')) %check if the user has entered a number
                    errordlg('Strings are not accepted here. Please enter a number without letter character (for example 1).', 'Incorrect Level entry');
                    app.CondFactTable.Data(indices(1),indices(2))="";
                end
            end

            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Study_design_tool.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Study_design_tool.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Study_design_tool.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Study_Design = uifigure('Visible', 'off');
            colormap(app.Study_Design, 'parula');
            app.Study_Design.Name = 'Study Design';
            app.Study_Design.CloseRequestFcn = createCallbackFcn(app, @Study_DesignCloseRequest, true);
            app.STUDYDESIGNLabel = uilabel(app.Study_Design);
            app.STUDYDESIGNLabel.Text = 'STUDY DESIGN';
            app.StudyNameEditFieldLabel = uilabel(app.Study_Design);
            app.StudyNameEditFieldLabel.Text = 'Study Name:';
            app.StudyName = uieditfield(app.Study_Design, 'text');
            app.CodeofFIRSTparticipantLabel = uilabel(app.Study_Design);
            app.CodeofFIRSTparticipantLabel.Text = 'Code of FIRST participant:';
            app.FirstSubjCode = uieditfield(app.Study_Design, 'text');
            app.FirstSubjCode.Tooltip = {'For EEGpal to detect the code of the participants, it must be in the name of the first file of the data table in the DATA window.'};
            app.NumberofconditionsEditFieldLabel = uilabel(app.Study_Design);
            app.NumberofconditionsEditFieldLabel.Text = 'Number of conditions:';
            app.NumCond = uieditfield(app.Study_Design, 'numeric');
            app.NumCond.Limits = [0 Inf];
            app.NumCond.RoundFractionalValues = 'on';
            app.NumCond.ValueDisplayFormat = '%.0f';
            app.NumCond.ValueChangedFcn = createCallbackFcn(app, @NumCondValueChanged, true);
            app.NumberoffactorsLabel = uilabel(app.Study_Design);
            app.NumberoffactorsLabel.Text = 'Number of factors:';
            app.NumFact = uieditfield(app.Study_Design, 'numeric');
            app.NumFact.Limits = [0 Inf];
            app.NumFact.RoundFractionalValues = 'on';
            app.NumFact.ValueDisplayFormat = '%.0f';
            app.NumFact.ValueChangedFcn = createCallbackFcn(app, @NumFactValueChanged, true);
            app.CondInstruction = uilabel(app.Study_Design);
            app.CondInstruction.Text = {'Step 1: Specify the number of conditions and '; 'factors'; ''; 'Step 2: In the first column of the Conditions table, enter condition name/code as it appears in the file name (case sensitive). '; 'If this pattern is not present in the filename, it will be ignored in statistical analysis.'; ''; 'Step 3:In the Conditions table, enter a value (1-9) to specify the level of factors associated with each condition. '; ''; 'Step 4 (faculataive): In the first colum of the Factors table, you can rename factors''name.'; ''; 'Step 5 : In the 2nd column of Factors table, you can specify whether the factor is between subject. If it is not ticked, it will be considered as within.subject.'};
            app.CondFactTable = uitable(app.Study_Design);
            app.CondFactTable.ColumnName = {'Conditions'};
            app.CondFactTable.RowName = {};
            app.CondFactTable.ColumnEditable = true;
            app.CondFactTable.CellEditCallback = createCallbackFcn(app, @CondFactTableCellEdit, true);
            app.FactTable = uitable(app.Study_Design);
            app.FactTable.ColumnName = {'Factors'; 'Between'};
            app.FactTable.RowName = {};
            app.FactTable.ColumnEditable = true;
            app.FactTable.CellEditCallback = createCallbackFcn(app, @FactTableCellEdit, true);
            app.SaveButton = uibutton(app.Study_Design, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Return to the main window'};
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.Study_Design, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.Study_Design, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            % Show the figure after all components are created
            app.Study_Design.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_DesignSetting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Study_Design)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Study_Design)
        end
    end
end